classdef mibDebrisRemovalGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure              matlab.ui.Figure
        HelpButton          matlab.ui.control.Button
        Current             matlab.ui.control.Button
        SettingsPanel       matlab.ui.container.Panel
        HighlightAs         matlab.ui.control.DropDown
        HighlightasLabel    matlab.ui.control.Label
        StrelSize           matlab.ui.control.NumericEditField
        StrelsizeLabel      matlab.ui.control.Label
        ObjectSizeTheshold  matlab.ui.control.NumericEditField
        ObjectsizethresholdEditFieldLabel  matlab.ui.control.Label
        Intensitythreshold  matlab.ui.control.NumericEditField
        IntensitythresholdEditFieldLabel  matlab.ui.control.Label
        DetectionMode       matlab.ui.container.ButtonGroup
        SelectedAreas       matlab.ui.control.RadioButton
        MaskedAreas         matlab.ui.control.RadioButton
        AutomaticDetection  matlab.ui.control.RadioButton
        AboutTextArea       matlab.ui.control.TextArea
        AboutLabel          matlab.ui.control.Label
        Close               matlab.ui.control.Button
        Remove              matlab.ui.control.Button
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = 'off';
        end

        % Button pushed function: Current, Remove
        function Remove_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % call a corresponding method of the controller class
            app.winController.Calculate(event.Source.Text);

        end

        % Button pushed function: Close
        function Close_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.winController.closeWindow();
        end

        % Button pushed function: HelpButton
        function Help_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.winController.Help_Callback();
            
        end

        % Value changed function: HighlightAs, Intensitythreshold, 
        % ObjectSizeTheshold, StrelSize
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Selection changed function: DetectionMode
        function DetectionModeSelectionChanged(app, event)
            selectedButton = app.DetectionMode.SelectedObject;
            app.winController.updateBatchOptFromGUI(event);
            if strcmp(event.Source.SelectedObject.Tag, 'AutomaticDetection')
                app.ObjectSizeTheshold.Enable = 'on';
                app.Intensitythreshold.Enable = 'on';
                app.StrelSize.Enable = 'on';
                app.HighlightAs.Enable = 'on';
            else
                app.ObjectSizeTheshold.Enable = 'off';
                app.Intensitythreshold.Enable = 'off';
                app.StrelSize.Enable = 'off';
                app.HighlightAs.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Debris removal';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.Remove = uibutton(app.Figure, 'push');
            app.Remove.ButtonPushedFcn = createCallbackFcn(app, @Remove_Callback, true);
            app.Remove.Text = 'Remove all';
            app.Close = uibutton(app.Figure, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @Close_Callback, true);
            app.Close.Text = 'Close';
            app.AboutLabel = uilabel(app.Figure);
            app.AboutLabel.Text = 'About:';
            app.AboutTextArea = uitextarea(app.Figure);
            app.AboutTextArea.Editable = 'off';
            app.AboutTextArea.Value = {'This tool removes debris from SB-EM datasets by replacing the area with debris to an average image of previous and following slices'};
            app.DetectionMode = uibuttongroup(app.Figure);
            app.DetectionMode.SelectionChangedFcn = createCallbackFcn(app, @DetectionModeSelectionChanged, true);
            app.DetectionMode.Title = 'Detection mode';
            app.AutomaticDetection = uiradiobutton(app.DetectionMode);
            app.AutomaticDetection.Text = 'Automatic detection';
            app.AutomaticDetection.Value = true;
            app.MaskedAreas = uiradiobutton(app.DetectionMode);
            app.MaskedAreas.Text = 'Masked areas';
            app.SelectedAreas = uiradiobutton(app.DetectionMode);
            app.SelectedAreas.Text = 'Selected areas';
            app.SettingsPanel = uipanel(app.Figure);
            app.SettingsPanel.Title = 'Settings';
            app.IntensitythresholdEditFieldLabel = uilabel(app.SettingsPanel);
            app.IntensitythresholdEditFieldLabel.Text = 'Intensity threshold:';
            app.Intensitythreshold = uieditfield(app.SettingsPanel, 'numeric');
            app.Intensitythreshold.Limits = [0 Inf];
            app.Intensitythreshold.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ObjectsizethresholdEditFieldLabel = uilabel(app.SettingsPanel);
            app.ObjectsizethresholdEditFieldLabel.Text = 'Object size threshold:';
            app.ObjectSizeTheshold = uieditfield(app.SettingsPanel, 'numeric');
            app.ObjectSizeTheshold.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ObjectSizeTheshold.Tooltip = {'Objects that are larger than this value will be considered as debris and removed'; ''};
            app.StrelsizeLabel = uilabel(app.SettingsPanel);
            app.StrelsizeLabel.Tooltip = {'Size of the strel element for morphological operations'; ''};
            app.StrelsizeLabel.Text = 'Strel size:';
            app.StrelSize = uieditfield(app.SettingsPanel, 'numeric');
            app.StrelSize.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.StrelSize.Tooltip = {'Objects that are larger than this value will be considered as debris and removed'; ''};
            app.HighlightasLabel = uilabel(app.SettingsPanel);
            app.HighlightasLabel.Text = 'Highlight  as';
            app.HighlightAs = uidropdown(app.SettingsPanel);
            app.HighlightAs.Items = {'mask', 'selection'};
            app.HighlightAs.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.HighlightAs.Tooltip = {'highlight removed areas as mask'};
            app.HighlightAs.Value = 'mask';
            app.Current = uibutton(app.Figure, 'push');
            app.Current.ButtonPushedFcn = createCallbackFcn(app, @Remove_Callback, true);
            app.Current.Text = 'Current';
            app.HelpButton = uibutton(app.Figure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mibDebrisRemovalGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end