classdef mibDeepActivationsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                matlab.ui.Figure
        GridLayoutMain        matlab.ui.container.GridLayout
        OptionsPanel          matlab.ui.container.Panel
        LayerLabel            matlab.ui.control.Label
        NetworkLayerName      matlab.ui.control.DropDown
        ImageLabel            matlab.ui.control.Label
        ImageFilename         matlab.ui.control.DropDown
        NumberFilersLabel     matlab.ui.control.Label
        PatchSizeLabel        matlab.ui.control.Label
        PreviousImageButton   matlab.ui.control.Button
        NextImageButton       matlab.ui.control.Button
        GridLayoutMiddle      matlab.ui.container.GridLayout
        OriginalimageLabel    matlab.ui.control.Label
        ActivationimageLabel  matlab.ui.control.Label
        ImageActivation       matlab.ui.control.Image
        ImageOriginal         matlab.ui.control.Image
        GridLayout            matlab.ui.container.GridLayout
        X1Label               matlab.ui.control.Label
        x1                    matlab.ui.control.Spinner
        Y1Label               matlab.ui.control.Label
        y1                    matlab.ui.control.Spinner
        Z1Label               matlab.ui.control.Label
        z1                    matlab.ui.control.Spinner
        GridLayout2           matlab.ui.container.GridLayout
        PatchZLabel           matlab.ui.control.Label
        patchZ                matlab.ui.control.Spinner
        FilterIdLabel         matlab.ui.control.Label
        filterId              matlab.ui.control.Spinner
        UpdatePatch           matlab.ui.control.Button
        CollageButton         matlab.ui.control.Button
        MinLabel              matlab.ui.control.Label
        MinLabelValue         matlab.ui.control.Label
        MaxLabel              matlab.ui.control.Label
        MaxLabelValue         matlab.ui.control.Label
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            
        end

        % Callback function
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Value changed function: ImageFilename
        function ImageFilenameValueChanged(app, event)
            app.winController.updateBatchOptFromGUI(event);
            app.winController.getNewImage();
        end

        % Value changed function: x1, y1, z1
        function ShiftImage(app, event)
            app.winController.updateBatchOptFromGUI(event);
            app.winController.ShiftImage(event);
        end

        % Value changed function: NetworkLayerName
        function NetworkLayerNameValueChanged(app, event)
            app.winController.updateBatchOptFromGUI(event);
            app.winController.getActivations();
        end

        % Value changed function: filterId, patchZ
        function updatePatchView(app, event)
            app.winController.updateBatchOptFromGUI(event);
            app.winController.showActivations();
        end

        % Button pushed function: UpdatePatch
        function UpdatePatchButtonPushed(app, event)
            app.winController.getActivations();
        end

        % Button pushed function: NextImageButton, 
        % PreviousImageButton
        function changeImage(app, event)
            app.winController.changeImage(event);
        end

        % Button pushed function: CollageButton
        function CollageButtonPushed(app, event)
            app.winController.makeCollage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Activations explorer';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.GridLayoutMain = uigridlayout(app.Figure);
            app.OptionsPanel = uipanel(app.GridLayoutMain);
            app.OptionsPanel.Title = 'Options';
            app.LayerLabel = uilabel(app.OptionsPanel);
            app.LayerLabel.Text = 'Layer:';
            app.NetworkLayerName = uidropdown(app.OptionsPanel);
            app.NetworkLayerName.ValueChangedFcn = createCallbackFcn(app, @NetworkLayerNameValueChanged, true);
            app.NetworkLayerName.Value = 'Option 4';
            app.ImageLabel = uilabel(app.OptionsPanel);
            app.ImageLabel.Text = 'Image:';
            app.ImageFilename = uidropdown(app.OptionsPanel);
            app.ImageFilename.ValueChangedFcn = createCallbackFcn(app, @ImageFilenameValueChanged, true);
            app.ImageFilename.Value = 'Option 4';
            app.NumberFilersLabel = uilabel(app.OptionsPanel);
            app.NumberFilersLabel.Text = 'Number of filers: ';
            app.PatchSizeLabel = uilabel(app.OptionsPanel);
            app.PatchSizeLabel.Text = 'Patch size: ';
            app.PreviousImageButton = uibutton(app.OptionsPanel, 'push');
            app.PreviousImageButton.ButtonPushedFcn = createCallbackFcn(app, @changeImage, true);
            app.PreviousImageButton.Icon = 'up_arrow.png';
            app.PreviousImageButton.Tooltip = {'Load previous image'};
            app.PreviousImageButton.Text = '';
            app.NextImageButton = uibutton(app.OptionsPanel, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @changeImage, true);
            app.NextImageButton.Icon = 'down_arrow.png';
            app.NextImageButton.Tooltip = {'Load next image'};
            app.NextImageButton.Text = '';
            app.GridLayoutMiddle = uigridlayout(app.GridLayoutMain);
            app.OriginalimageLabel = uilabel(app.GridLayoutMiddle);
            app.OriginalimageLabel.Text = 'Original image';
            app.ActivationimageLabel = uilabel(app.GridLayoutMiddle);
            app.ActivationimageLabel.Text = 'Activation image';
            app.ImageActivation = uiimage(app.GridLayoutMiddle);
            app.ImageOriginal = uiimage(app.GridLayoutMiddle);
            app.GridLayout = uigridlayout(app.GridLayoutMiddle);
            app.X1Label = uilabel(app.GridLayout);
            app.X1Label.Text = 'X1';
            app.x1 = uispinner(app.GridLayout);
            app.x1.ValueChangedFcn = createCallbackFcn(app, @ShiftImage, true);
            app.x1.Value = 1;
            app.Y1Label = uilabel(app.GridLayout);
            app.Y1Label.Text = 'Y1';
            app.y1 = uispinner(app.GridLayout);
            app.y1.ValueChangedFcn = createCallbackFcn(app, @ShiftImage, true);
            app.y1.Value = 1;
            app.Z1Label = uilabel(app.GridLayout);
            app.Z1Label.Text = 'Z1';
            app.z1 = uispinner(app.GridLayout);
            app.z1.ValueChangedFcn = createCallbackFcn(app, @ShiftImage, true);
            app.z1.Value = 1;
            app.GridLayout2 = uigridlayout(app.GridLayoutMiddle);
            app.PatchZLabel = uilabel(app.GridLayout2);
            app.PatchZLabel.Text = 'Patch Z';
            app.patchZ = uispinner(app.GridLayout2);
            app.patchZ.ValueChangedFcn = createCallbackFcn(app, @updatePatchView, true);
            app.patchZ.Value = 1;
            app.FilterIdLabel = uilabel(app.GridLayout2);
            app.FilterIdLabel.Text = 'Filter Id';
            app.filterId = uispinner(app.GridLayout2);
            app.filterId.ValueChangedFcn = createCallbackFcn(app, @updatePatchView, true);
            app.filterId.Value = 1;
            app.UpdatePatch = uibutton(app.GridLayout2, 'push');
            app.UpdatePatch.ButtonPushedFcn = createCallbackFcn(app, @UpdatePatchButtonPushed, true);
            app.UpdatePatch.Text = 'Update';
            app.CollageButton = uibutton(app.GridLayout2, 'push');
            app.CollageButton.ButtonPushedFcn = createCallbackFcn(app, @CollageButtonPushed, true);
            app.CollageButton.Tooltip = {'Make a collage image with activations'};
            app.CollageButton.Text = 'Collage';
            app.MinLabel = uilabel(app.GridLayout2);
            app.MinLabel.Text = 'Min:';
            app.MinLabelValue = uilabel(app.GridLayout2);
            app.MinLabelValue.Text = '0';
            app.MaxLabel = uilabel(app.GridLayout2);
            app.MaxLabel.Text = 'Max:';
            app.MaxLabelValue = uilabel(app.GridLayout2);
            app.MaxLabelValue.Text = '1';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mibDeepActivationsGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end