classdef mibDeepAugmentSettingsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                        matlab.ui.Figure
        mainGridLayout                matlab.ui.container.GridLayout
        bottomGridLayout              matlab.ui.container.GridLayout
        Close                         matlab.ui.control.Button
        OK                            matlab.ui.control.Button
        PreviousseedButton            matlab.ui.control.Button
        RandomSeedEdit                matlab.ui.control.NumericEditField
        RandomseedEditFieldLabel      matlab.ui.control.Label
        HelpButton                    matlab.ui.control.Button
        PreviewButton                 matlab.ui.control.Button
        SettingsforPreviewButton      matlab.ui.control.Button
        middlePanel                   matlab.ui.container.Panel
        FillValue                     matlab.ui.control.NumericEditField
        Fraction                      matlab.ui.control.Spinner
        NoiseandblurPanel             matlab.ui.container.Panel
        ImageBlur_Max                 matlab.ui.control.Spinner
        ImageBlur_Min                 matlab.ui.control.Spinner
        ImageBlur_Probability         matlab.ui.control.Spinner
        GaussianNoise_Max             matlab.ui.control.Spinner
        GaussianNoise_Min             matlab.ui.control.Spinner
        GaussianNoise_Probability     matlab.ui.control.Spinner
        PoissonNoise_Probability      matlab.ui.control.Spinner
        ImageBlur_Preview             matlab.ui.control.Button
        ImageBlur_Enable              matlab.ui.control.CheckBox
        GaussianNoise_Preview         matlab.ui.control.Button
        GaussianNoise_Enable          matlab.ui.control.CheckBox
        PoissonNoise_Preview          matlab.ui.control.Button
        PoissonNoise_Enable           matlab.ui.control.CheckBox
        IntensityandcolorPanel        matlab.ui.container.Panel
        SaturationJitter_Max          matlab.ui.control.Spinner
        SaturationJitter_Min          matlab.ui.control.Spinner
        SaturationJitter_Probability  matlab.ui.control.Spinner
        HueJitter_Max                 matlab.ui.control.Spinner
        HueJitter_Min                 matlab.ui.control.Spinner
        HueJitter_Probability         matlab.ui.control.Spinner
        ContrastJitter_Max            matlab.ui.control.Spinner
        ContrastJitter_Min            matlab.ui.control.Spinner
        ContrastJitter_Probability    matlab.ui.control.Spinner
        BrightnessJitter_Max          matlab.ui.control.Spinner
        BrightnessJitter_Min          matlab.ui.control.Spinner
        BrightnessJitter_Probability  matlab.ui.control.Spinner
        SaturationJitter_Preview      matlab.ui.control.Button
        SaturationJitter_Enable       matlab.ui.control.CheckBox
        HueJitter_Preview             matlab.ui.control.Button
        HueJitter_Enable              matlab.ui.control.CheckBox
        ContrastJitter_Preview        matlab.ui.control.Button
        ContrastJitter_Enable         matlab.ui.control.CheckBox
        BrightnessJitter_Preview      matlab.ui.control.Button
        BrightnessJitter_Enable       matlab.ui.control.CheckBox
        ShearPanel                    matlab.ui.container.Panel
        RandYShear_Max                matlab.ui.control.Spinner
        RandYShear_Min                matlab.ui.control.Spinner
        RandYShear_Probability        matlab.ui.control.Spinner
        RandXShear_Max                matlab.ui.control.Spinner
        RandXShear_Min                matlab.ui.control.Spinner
        RandXShear_Probability        matlab.ui.control.Spinner
        RandYShear_Preview            matlab.ui.control.Button
        RandYShear_Enable             matlab.ui.control.CheckBox
        RandXShear_Preview            matlab.ui.control.Button
        RandXShear_Enable             matlab.ui.control.CheckBox
        RotationsPanel                matlab.ui.container.Panel
        RandRotation_Max              matlab.ui.control.Spinner
        RandRotation_Min              matlab.ui.control.Spinner
        RandRotation_Probability      matlab.ui.control.Spinner
        ReflectedRotation90_Probability  matlab.ui.control.Spinner
        Rotation90_Probability        matlab.ui.control.Spinner
        RandRotation_Preview          matlab.ui.control.Button
        RandRotation_Enable           matlab.ui.control.CheckBox
        ReflectedRotation90_Preview   matlab.ui.control.Button
        ReflectedRotation90_Enable    matlab.ui.control.CheckBox
        Rotation90_Preview            matlab.ui.control.Button
        Rotation90_Enable             matlab.ui.control.CheckBox
        ScalePanel                    matlab.ui.container.Panel
        RandYScale_Max                matlab.ui.control.Spinner
        RandYScale_Min                matlab.ui.control.Spinner
        RandYScale_Probability        matlab.ui.control.Spinner
        RandXScale_Max                matlab.ui.control.Spinner
        RandXScale_Min                matlab.ui.control.Spinner
        RandXScale_Probability        matlab.ui.control.Spinner
        RandScale_Max                 matlab.ui.control.Spinner
        RandScale_Min                 matlab.ui.control.Spinner
        RandScale_Probability         matlab.ui.control.Spinner
        RandYScale_Preview            matlab.ui.control.Button
        RandYScale_Enable             matlab.ui.control.CheckBox
        RandXScale_Preview            matlab.ui.control.Button
        RandXScale_Enable             matlab.ui.control.CheckBox
        RandScale_Preview             matlab.ui.control.Button
        RandScale_Enable              matlab.ui.control.CheckBox
        ReflectionsPanel              matlab.ui.container.Panel
        RandZReflection_Probability   matlab.ui.control.Spinner
        RandYReflection_Probability   matlab.ui.control.Spinner
        RandXReflection_Probability   matlab.ui.control.Spinner
        RandZReflection_Preview       matlab.ui.control.Button
        RandZReflection_Enable        matlab.ui.control.CheckBox
        RandYReflection_Preview       matlab.ui.control.Button
        RandYReflection_Enable        matlab.ui.control.CheckBox
        RandXReflection_Preview       matlab.ui.control.Button
        RandXReflection_Enable        matlab.ui.control.CheckBox
        FillValue_Label               matlab.ui.control.Label
        Fraction_Label                matlab.ui.control.Label
        topGridLayout                 matlab.ui.container.GridLayout
        Panel                         matlab.ui.container.Panel
        DisableButton                 matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        infoText                      matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            
        end

        % Button pushed function: OK
        function OK_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % call a corresponding method of the controller class
            
            app.winController.Accept();
        end

        % Button pushed function: Close
        function Close_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.winController.closeWindow();
        end

        % Button pushed function: HelpButton
        function Help_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global mibPath;
            web(fullfile(mibPath, 'techdoc', 'html', 'ug_gui_menu_tools_deeplearning_train.html'), '-helpbrowser');
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Value changed function: BrightnessJitter_Enable, 
        % ContrastJitter_Enable, GaussianNoise_Enable, HueJitter_Enable, 
        % ImageBlur_Enable, PoissonNoise_Enable, RandRotation_Enable, 
        % RandScale_Enable, RandXReflection_Enable, RandXScale_Enable, 
        % RandXShear_Enable, RandYReflection_Enable, RandYScale_Enable, 
        % RandYShear_Enable, RandZReflection_Enable, 
        % ReflectedRotation90_Enable, Rotation90_Enable, 
        % SaturationJitter_Enable
        function enableStateChange(app, event)
            app.winController.enableStateChange(event);
        end

        % Value changed function: BrightnessJitter_Max, 
        % BrightnessJitter_Min, BrightnessJitter_Probability, 
        % ContrastJitter_Max, ContrastJitter_Min, 
        % ContrastJitter_Probability, FillValue, Fraction, 
        % GaussianNoise_Max, GaussianNoise_Min, GaussianNoise_Probability, 
        % HueJitter_Max, HueJitter_Min, HueJitter_Probability, 
        % ImageBlur_Min, ImageBlur_Probability, PoissonNoise_Probability, 
        % RandRotation_Max, RandRotation_Min, RandRotation_Probability, 
        % RandScale_Max, RandScale_Min, RandScale_Probability, 
        % RandXReflection_Probability, RandXScale_Max, RandXScale_Min, 
        % RandXScale_Probability, RandXShear_Max, RandXShear_Min, 
        % RandXShear_Probability, RandYReflection_Probability, 
        % RandYScale_Max, RandYScale_Min, RandYScale_Probability, 
        % RandYShear_Max, RandYShear_Min, RandYShear_Probability, 
        % RandZReflection_Probability, ReflectedRotation90_Probability, 
        % Rotation90_Probability, SaturationJitter_Max, 
        % SaturationJitter_Min, SaturationJitter_Probability
        function updateParameter(app, event)
            app.winController.updateAugmentationParameters(event);
        end

        % Button pushed function: DisableButton
        function disableAugmentations(app, event)
            app.winController.disableAugmentations();
        end

        % Button pushed function: ResetButton
        function resetAugmentations(app, event)
            app.winController.resetAugmentations();
        end

        % Button pushed function: SettingsforPreviewButton
        function setPreviewAugmentationSettings(app, event)
            app.winController.setPreviewAugmentationSettings();
        end

        % Button pushed function: BrightnessJitter_Preview, 
        % ContrastJitter_Preview, GaussianNoise_Preview, 
        % HueJitter_Preview, ImageBlur_Preview, PoissonNoise_Preview, 
        % PreviewButton, RandRotation_Preview, RandScale_Preview, 
        % RandXReflection_Preview, RandXScale_Preview, RandXShear_Preview, 
        % RandYReflection_Preview, RandYScale_Preview, RandYShear_Preview, 
        % RandZReflection_Preview, ReflectedRotation90_Preview, 
        % Rotation90_Preview, SaturationJitter_Preview
        function previewAugmentations(app, event)
            switch event.Source.Tag
                case 'PreviewButton'
                    app.winController.previewAugmentations();
                otherwise
                    augmentationName = event.Source.Tag(1:strfind(event.Source.Tag, '_')-1);
                    app.winController.previewAugmentations(augmentationName);
            end
        end

        % Button pushed function: PreviousseedButton
        function restorePreviousSeed(app, event)
            app.winController.restorePreviousSeed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Child GUI Name';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.mainGridLayout = uigridlayout(app.Figure);
            app.topGridLayout = uigridlayout(app.mainGridLayout);
            app.Image = uiimage(app.topGridLayout);
            app.Image.ImageSource = 'mib_question.png';
            app.infoText = uilabel(app.topGridLayout);
            app.infoText.Text = {'Augmentations are an easy way to extend the training base by applying various filters to the input image patches.'; 'In a panel below, use checkboxes to turn on augmenters, yellow boxes to specify the probability of each augmenter, and the blue boxes to set random variation for each augmenter.'; 'Use the Eye button to preview selected augmentations'};
            app.Panel = uipanel(app.topGridLayout);
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetAugmentations, true);
            app.ResetButton.Tooltip = {'reset all augmentations into their default states'};
            app.ResetButton.Text = 'Reset';
            app.DisableButton = uibutton(app.Panel, 'push');
            app.DisableButton.ButtonPushedFcn = createCallbackFcn(app, @disableAugmentations, true);
            app.DisableButton.Tooltip = {'Disable all augmentations'};
            app.DisableButton.Text = 'Disable';
            app.middlePanel = uipanel(app.mainGridLayout);
            app.Fraction_Label = uilabel(app.middlePanel);
            app.Fraction_Label.Tooltip = {'fraction of images to be augmented (def=1)'};
            app.Fraction_Label.Text = 'Fraction';
            app.FillValue_Label = uilabel(app.middlePanel);
            app.FillValue_Label.Tooltip = {'fill value for out-of-bounds points when rotating [0-Inf, def=255]'};
            app.FillValue_Label.Text = 'FillValue';
            app.ReflectionsPanel = uipanel(app.middlePanel);
            app.ReflectionsPanel.Title = 'Reflections';
            app.RandXReflection_Enable = uicheckbox(app.ReflectionsPanel);
            app.RandXReflection_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.RandXReflection_Enable.Tooltip = {'random reflection in the left-right direction, (def=0.2)'};
            app.RandXReflection_Enable.Text = 'RandXReflection';
            app.RandXReflection_Preview = uibutton(app.ReflectionsPanel, 'push');
            app.RandXReflection_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.RandXReflection_Preview.Icon = 'eye.png';
            app.RandXReflection_Preview.Tooltip = {'press to preview RandXReflection augmentations'};
            app.RandXReflection_Preview.Text = '';
            app.RandYReflection_Enable = uicheckbox(app.ReflectionsPanel);
            app.RandYReflection_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.RandYReflection_Enable.Tooltip = {'random reflection in the up-down direction (def=0.2)'};
            app.RandYReflection_Enable.Text = 'RandYReflection';
            app.RandYReflection_Preview = uibutton(app.ReflectionsPanel, 'push');
            app.RandYReflection_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.RandYReflection_Preview.Icon = 'eye.png';
            app.RandYReflection_Preview.Tooltip = {'press to preview RandYReflection augmentations'};
            app.RandYReflection_Preview.Text = '';
            app.RandZReflection_Enable = uicheckbox(app.ReflectionsPanel);
            app.RandZReflection_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.RandZReflection_Enable.Tooltip = {'random reflection in the top-bottom direction (def=0.2)'};
            app.RandZReflection_Enable.Text = 'RandZReflection';
            app.RandZReflection_Preview = uibutton(app.ReflectionsPanel, 'push');
            app.RandZReflection_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.RandZReflection_Preview.Icon = 'eye.png';
            app.RandZReflection_Preview.Tooltip = {'press to preview RandZReflection augmentations'};
            app.RandZReflection_Preview.Text = '';
            app.RandXReflection_Probability = uispinner(app.ReflectionsPanel);
            app.RandXReflection_Probability.Step = 0.05;
            app.RandXReflection_Probability.Limits = [0 1];
            app.RandXReflection_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandXReflection_Probability.Tooltip = {'random reflection in the left-right direction, (def=0.2)'};
            app.RandXReflection_Probability.Value = 0.1;
            app.RandYReflection_Probability = uispinner(app.ReflectionsPanel);
            app.RandYReflection_Probability.Step = 0.05;
            app.RandYReflection_Probability.Limits = [0 1];
            app.RandYReflection_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandYReflection_Probability.Tooltip = {'random reflection in the up-down direction (def=0.2)'};
            app.RandYReflection_Probability.Value = 0.1;
            app.RandZReflection_Probability = uispinner(app.ReflectionsPanel);
            app.RandZReflection_Probability.Step = 0.05;
            app.RandZReflection_Probability.Limits = [0 1];
            app.RandZReflection_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandZReflection_Probability.Tooltip = {'random reflection in the top-bottom direction (def=0.2)'};
            app.RandZReflection_Probability.Value = 0.1;
            app.ScalePanel = uipanel(app.middlePanel);
            app.ScalePanel.Title = 'Scale';
            app.RandScale_Enable = uicheckbox(app.ScalePanel);
            app.RandScale_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.RandScale_Enable.Tooltip = {'random scaling, range 0-Inf, (def=0.1; 1.0...1.1)'};
            app.RandScale_Enable.Text = 'RandScale';
            app.RandScale_Preview = uibutton(app.ScalePanel, 'push');
            app.RandScale_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.RandScale_Preview.Icon = 'eye.png';
            app.RandScale_Preview.Tooltip = {'press to preview RandScale augmentations'};
            app.RandScale_Preview.Text = '';
            app.RandXScale_Enable = uicheckbox(app.ScalePanel);
            app.RandXScale_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.RandXScale_Enable.Tooltip = {'random X scaling, range 0-Inf, (def=0.1; 1.0...1.1)'};
            app.RandXScale_Enable.Text = 'RandXScale';
            app.RandXScale_Preview = uibutton(app.ScalePanel, 'push');
            app.RandXScale_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.RandXScale_Preview.Icon = 'eye.png';
            app.RandXScale_Preview.Tooltip = {'press to preview RandXScale augmentations'};
            app.RandXScale_Preview.Text = '';
            app.RandYScale_Enable = uicheckbox(app.ScalePanel);
            app.RandYScale_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.RandYScale_Enable.Tooltip = {'random Y scaling, range 0-Inf, (def=0.1; 1.0...1.1)'};
            app.RandYScale_Enable.Text = 'RandYScale';
            app.RandYScale_Preview = uibutton(app.ScalePanel, 'push');
            app.RandYScale_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.RandYScale_Preview.Icon = 'eye.png';
            app.RandYScale_Preview.Tooltip = {'press to preview RandYScale augmentations'};
            app.RandYScale_Preview.Text = '';
            app.RandScale_Probability = uispinner(app.ScalePanel);
            app.RandScale_Probability.Step = 0.05;
            app.RandScale_Probability.Limits = [0 1];
            app.RandScale_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandScale_Probability.Tooltip = {'random scaling, range 0-Inf, (def=0.1; 1.0...1.1)'};
            app.RandScale_Probability.Value = 0.1;
            app.RandScale_Min = uispinner(app.ScalePanel);
            app.RandScale_Min.Step = 0.01;
            app.RandScale_Min.Limits = [0 Inf];
            app.RandScale_Min.ValueDisplayFormat = '%11.3g';
            app.RandScale_Min.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandScale_Min.Tooltip = {'random scaling, range 0-Inf, (def=0.1; 1.0...1.1)'};
            app.RandScale_Min.Value = 1;
            app.RandScale_Max = uispinner(app.ScalePanel);
            app.RandScale_Max.Step = 0.01;
            app.RandScale_Max.Limits = [0 Inf];
            app.RandScale_Max.ValueDisplayFormat = '%11.3g';
            app.RandScale_Max.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandScale_Max.Tooltip = {'random scaling, range 0-Inf, (def=0.1; 1.0...1.1)'};
            app.RandScale_Max.Value = 1.1;
            app.RandXScale_Probability = uispinner(app.ScalePanel);
            app.RandXScale_Probability.Step = 0.05;
            app.RandXScale_Probability.Limits = [0 1];
            app.RandXScale_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandXScale_Probability.Tooltip = {'random X scaling, range 0-Inf, (def=0.1; 1.0...1.1)'};
            app.RandXScale_Probability.Value = 0.1;
            app.RandXScale_Min = uispinner(app.ScalePanel);
            app.RandXScale_Min.Step = 0.01;
            app.RandXScale_Min.Limits = [0 Inf];
            app.RandXScale_Min.ValueDisplayFormat = '%11.3g';
            app.RandXScale_Min.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandXScale_Min.Tooltip = {'random X scaling, range 0-Inf, (def=0.1; 1.0...1.1)'};
            app.RandXScale_Min.Value = 1;
            app.RandXScale_Max = uispinner(app.ScalePanel);
            app.RandXScale_Max.Step = 0.01;
            app.RandXScale_Max.Limits = [0 Inf];
            app.RandXScale_Max.ValueDisplayFormat = '%11.3g';
            app.RandXScale_Max.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandXScale_Max.Tooltip = {'random X scaling, range 0-Inf, (def=0.1; 1.0...1.1)'};
            app.RandXScale_Max.Value = 1.1;
            app.RandYScale_Probability = uispinner(app.ScalePanel);
            app.RandYScale_Probability.Step = 0.05;
            app.RandYScale_Probability.Limits = [0 1];
            app.RandYScale_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandYScale_Probability.Tooltip = {'random Y scaling, range 0-Inf, (def=0.1; 1.0...1.1)'};
            app.RandYScale_Probability.Value = 0.1;
            app.RandYScale_Min = uispinner(app.ScalePanel);
            app.RandYScale_Min.Step = 0.01;
            app.RandYScale_Min.Limits = [0 Inf];
            app.RandYScale_Min.ValueDisplayFormat = '%11.3g';
            app.RandYScale_Min.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandYScale_Min.Tooltip = {'random Y scaling, range 0-Inf, (def=0.1; 1.0...1.1)'};
            app.RandYScale_Min.Value = 1;
            app.RandYScale_Max = uispinner(app.ScalePanel);
            app.RandYScale_Max.Step = 0.01;
            app.RandYScale_Max.Limits = [0 Inf];
            app.RandYScale_Max.ValueDisplayFormat = '%11.3g';
            app.RandYScale_Max.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandYScale_Max.Tooltip = {'random Y scaling, range 0-Inf, (def=0.1; 1.0...1.1)'};
            app.RandYScale_Max.Value = 1.1;
            app.RotationsPanel = uipanel(app.middlePanel);
            app.RotationsPanel.Title = 'Rotations';
            app.Rotation90_Enable = uicheckbox(app.RotationsPanel);
            app.Rotation90_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.Rotation90_Enable.Tooltip = {'allow 90 degree rotation clock and anticlockwise directions (def=0.2)'};
            app.Rotation90_Enable.Text = 'Rotation90';
            app.Rotation90_Preview = uibutton(app.RotationsPanel, 'push');
            app.Rotation90_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.Rotation90_Preview.Icon = 'eye.png';
            app.Rotation90_Preview.Tooltip = {'press to preview Rotation90 augmentations'};
            app.Rotation90_Preview.Text = '';
            app.ReflectedRotation90_Enable = uicheckbox(app.RotationsPanel);
            app.ReflectedRotation90_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.ReflectedRotation90_Enable.Tooltip = {'allow 90 degree rotation of the left-right reflected dataset (def=0.2)'};
            app.ReflectedRotation90_Enable.Text = 'ReflectedRotation90';
            app.ReflectedRotation90_Preview = uibutton(app.RotationsPanel, 'push');
            app.ReflectedRotation90_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.ReflectedRotation90_Preview.Icon = 'eye.png';
            app.ReflectedRotation90_Preview.Tooltip = {'press to preview ReflectedRotation90 augmentations'};
            app.ReflectedRotation90_Preview.Text = '';
            app.RandRotation_Enable = uicheckbox(app.RotationsPanel);
            app.RandRotation_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.RandRotation_Enable.Tooltip = {'random rotations, in degrees from -90 to 90, (def=0.15; -10...10)'};
            app.RandRotation_Enable.Text = 'RandRotation';
            app.RandRotation_Preview = uibutton(app.RotationsPanel, 'push');
            app.RandRotation_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.RandRotation_Preview.Icon = 'eye.png';
            app.RandRotation_Preview.Tooltip = {'press to preview RandRotation augmentations'};
            app.RandRotation_Preview.Text = '';
            app.Rotation90_Probability = uispinner(app.RotationsPanel);
            app.Rotation90_Probability.Step = 0.05;
            app.Rotation90_Probability.Limits = [0 1];
            app.Rotation90_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.Rotation90_Probability.Tooltip = {'allow 90 degree rotation clock and anticlockwise directions (def=0.2)'};
            app.Rotation90_Probability.Value = 0.1;
            app.ReflectedRotation90_Probability = uispinner(app.RotationsPanel);
            app.ReflectedRotation90_Probability.Step = 0.05;
            app.ReflectedRotation90_Probability.Limits = [0 1];
            app.ReflectedRotation90_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.ReflectedRotation90_Probability.Tooltip = {'allow 90 degree rotation of the left-right reflected dataset (def=0.2)'};
            app.ReflectedRotation90_Probability.Value = 0.1;
            app.RandRotation_Probability = uispinner(app.RotationsPanel);
            app.RandRotation_Probability.Step = 0.05;
            app.RandRotation_Probability.Limits = [0 1];
            app.RandRotation_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandRotation_Probability.Tooltip = {'random rotations, in degrees from -90 to 90, (def=0.15; -10...10)'};
            app.RandRotation_Probability.Value = 0.1;
            app.RandRotation_Min = uispinner(app.RotationsPanel);
            app.RandRotation_Min.Limits = [-90 0];
            app.RandRotation_Min.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandRotation_Min.Tooltip = {'random rotations, in degrees from -90 to 90, (def=0.15; -10...10)'};
            app.RandRotation_Min.Value = -10;
            app.RandRotation_Max = uispinner(app.RotationsPanel);
            app.RandRotation_Max.Limits = [0 90];
            app.RandRotation_Max.RoundFractionalValues = 'on';
            app.RandRotation_Max.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandRotation_Max.Tooltip = {'random rotations, in degrees from -90 to 90, (def=0.15; -10...10)'};
            app.RandRotation_Max.Value = 10;
            app.ShearPanel = uipanel(app.middlePanel);
            app.ShearPanel.Title = 'Shear';
            app.RandXShear_Enable = uicheckbox(app.ShearPanel);
            app.RandXShear_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.RandXShear_Enable.Tooltip = {'horizontal random shear, in degrees in the range -90...90, (def=0.1; -10...10)'};
            app.RandXShear_Enable.Text = 'RandXShear';
            app.RandXShear_Preview = uibutton(app.ShearPanel, 'push');
            app.RandXShear_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.RandXShear_Preview.Icon = 'eye.png';
            app.RandXShear_Preview.Tooltip = {'press to preview RandXShear augmentations'};
            app.RandXShear_Preview.Text = '';
            app.RandYShear_Enable = uicheckbox(app.ShearPanel);
            app.RandYShear_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.RandYShear_Enable.Tooltip = {'vertical random shear, in degrees in the range -90...90, (def=0.1; -10...10)'};
            app.RandYShear_Enable.Text = 'RandYShear';
            app.RandYShear_Preview = uibutton(app.ShearPanel, 'push');
            app.RandYShear_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.RandYShear_Preview.Icon = 'eye.png';
            app.RandYShear_Preview.Tooltip = {'press to preview RandYShear augmentations'};
            app.RandYShear_Preview.Text = '';
            app.RandXShear_Probability = uispinner(app.ShearPanel);
            app.RandXShear_Probability.Step = 0.05;
            app.RandXShear_Probability.Limits = [0 1];
            app.RandXShear_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandXShear_Probability.Tooltip = {'horizontal random shear, in degrees in the range -90...90, (def=0.1; -10...10)'};
            app.RandXShear_Probability.Value = 0.1;
            app.RandXShear_Min = uispinner(app.ShearPanel);
            app.RandXShear_Min.Limits = [-90 0];
            app.RandXShear_Min.RoundFractionalValues = 'on';
            app.RandXShear_Min.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandXShear_Min.Tooltip = {'horizontal random shear, in degrees in the range -90...90, (def=0.1; -10...10)'};
            app.RandXShear_Min.Value = -10;
            app.RandXShear_Max = uispinner(app.ShearPanel);
            app.RandXShear_Max.Limits = [0 90];
            app.RandXShear_Max.RoundFractionalValues = 'on';
            app.RandXShear_Max.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandXShear_Max.Tooltip = {'horizontal random shear, in degrees in the range -90...90, (def=0.1; -10...10)'};
            app.RandXShear_Max.Value = 10;
            app.RandYShear_Probability = uispinner(app.ShearPanel);
            app.RandYShear_Probability.Step = 0.05;
            app.RandYShear_Probability.Limits = [0 1];
            app.RandYShear_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandYShear_Probability.Tooltip = {'vertical random shear, in degrees in the range -90...90, (def=0.1; -10...10)'};
            app.RandYShear_Probability.Value = 0.1;
            app.RandYShear_Min = uispinner(app.ShearPanel);
            app.RandYShear_Min.Limits = [-90 0];
            app.RandYShear_Min.RoundFractionalValues = 'on';
            app.RandYShear_Min.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandYShear_Min.Tooltip = {'vertical random shear, in degrees in the range -90...90, (def=0.1; -10...10)'};
            app.RandYShear_Min.Value = -10;
            app.RandYShear_Max = uispinner(app.ShearPanel);
            app.RandYShear_Max.Limits = [0 90];
            app.RandYShear_Max.RoundFractionalValues = 'on';
            app.RandYShear_Max.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.RandYShear_Max.Tooltip = {'vertical random shear, in degrees in the range -90...90, (def=0.1; -10...10)'};
            app.RandYShear_Max.Value = 10;
            app.IntensityandcolorPanel = uipanel(app.middlePanel);
            app.IntensityandcolorPanel.Title = 'Intensity and color';
            app.BrightnessJitter_Enable = uicheckbox(app.IntensityandcolorPanel);
            app.BrightnessJitter_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.BrightnessJitter_Enable.Text = 'BrightnessJitter';
            app.BrightnessJitter_Preview = uibutton(app.IntensityandcolorPanel, 'push');
            app.BrightnessJitter_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.BrightnessJitter_Preview.Icon = 'eye.png';
            app.BrightnessJitter_Preview.Tooltip = {'press to preview BrightnessJitter augmentations'};
            app.BrightnessJitter_Preview.Text = '';
            app.ContrastJitter_Enable = uicheckbox(app.IntensityandcolorPanel);
            app.ContrastJitter_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.ContrastJitter_Enable.Tooltip = {'jitter of Contrast using a random value in the range 0...Inf (def=0.2; 0.9...1.1)'};
            app.ContrastJitter_Enable.Text = 'ContrastJitter';
            app.ContrastJitter_Preview = uibutton(app.IntensityandcolorPanel, 'push');
            app.ContrastJitter_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.ContrastJitter_Preview.Icon = 'eye.png';
            app.ContrastJitter_Preview.Tooltip = {'press to preview ContrastJitter augmentations'};
            app.ContrastJitter_Preview.Text = '';
            app.HueJitter_Enable = uicheckbox(app.IntensityandcolorPanel);
            app.HueJitter_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.HueJitter_Enable.Tooltip = {'RGB ONLY: jitter of Hue using a random value in the range -1...1 (def=0.2; -0.03...0.03)'};
            app.HueJitter_Enable.Text = 'HueJitter';
            app.HueJitter_Preview = uibutton(app.IntensityandcolorPanel, 'push');
            app.HueJitter_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.HueJitter_Preview.Icon = 'eye.png';
            app.HueJitter_Preview.Tooltip = {'press to preview HueJitter augmentations'};
            app.HueJitter_Preview.Text = '';
            app.SaturationJitter_Enable = uicheckbox(app.IntensityandcolorPanel);
            app.SaturationJitter_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.SaturationJitter_Enable.Tooltip = {'RGB ONLY: jitter of Saturation using a random value in the range -1...1 (def=0.2; -0.05...0.05)'};
            app.SaturationJitter_Enable.Text = 'SaturationJitter';
            app.SaturationJitter_Preview = uibutton(app.IntensityandcolorPanel, 'push');
            app.SaturationJitter_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.SaturationJitter_Preview.Icon = 'eye.png';
            app.SaturationJitter_Preview.Tooltip = {'press to preview SaturationJitter augmentations'};
            app.SaturationJitter_Preview.Text = '';
            app.BrightnessJitter_Probability = uispinner(app.IntensityandcolorPanel);
            app.BrightnessJitter_Probability.Step = 0.05;
            app.BrightnessJitter_Probability.Limits = [0 1];
            app.BrightnessJitter_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.BrightnessJitter_Probability.Tooltip = {'jitter of Brightness using a random value in the range -1...1 (def=0.2; -0.1...0.1)'};
            app.BrightnessJitter_Probability.Value = 0.2;
            app.BrightnessJitter_Min = uispinner(app.IntensityandcolorPanel);
            app.BrightnessJitter_Min.Step = 0.01;
            app.BrightnessJitter_Min.Limits = [-1 0];
            app.BrightnessJitter_Min.ValueDisplayFormat = '%11.3g';
            app.BrightnessJitter_Min.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.BrightnessJitter_Min.Tooltip = {'jitter of Brightness using a random value in the range -1...1 (def=0.2; -0.1...0.1)'};
            app.BrightnessJitter_Min.Value = -0.1;
            app.BrightnessJitter_Max = uispinner(app.IntensityandcolorPanel);
            app.BrightnessJitter_Max.Step = 0.01;
            app.BrightnessJitter_Max.Limits = [0 1];
            app.BrightnessJitter_Max.ValueDisplayFormat = '%11.3g';
            app.BrightnessJitter_Max.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.BrightnessJitter_Max.Tooltip = {'jitter of Brightness using a random value in the range -1...1 (def=0.2; -0.1...0.1)'};
            app.BrightnessJitter_Max.Value = 0.1;
            app.ContrastJitter_Probability = uispinner(app.IntensityandcolorPanel);
            app.ContrastJitter_Probability.Step = 0.05;
            app.ContrastJitter_Probability.Limits = [0 1];
            app.ContrastJitter_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.ContrastJitter_Probability.Tooltip = {'jitter of Contrast using a random value in the range 0...Inf (def=0.2; 0.9...1.1)'};
            app.ContrastJitter_Probability.Value = 0.2;
            app.ContrastJitter_Min = uispinner(app.IntensityandcolorPanel);
            app.ContrastJitter_Min.Step = 0.01;
            app.ContrastJitter_Min.Limits = [0 Inf];
            app.ContrastJitter_Min.ValueDisplayFormat = '%11.3g';
            app.ContrastJitter_Min.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.ContrastJitter_Min.Tooltip = {'jitter of Contrast using a random value in the range 0...Inf (def=0.2; 0.9...1.1)'};
            app.ContrastJitter_Min.Value = 0.9;
            app.ContrastJitter_Max = uispinner(app.IntensityandcolorPanel);
            app.ContrastJitter_Max.Step = 0.01;
            app.ContrastJitter_Max.Limits = [0 Inf];
            app.ContrastJitter_Max.ValueDisplayFormat = '%11.3g';
            app.ContrastJitter_Max.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.ContrastJitter_Max.Tooltip = {'jitter of Contrast using a random value in the range 0...Inf (def=0.2; 0.9...1.1)'};
            app.ContrastJitter_Max.Value = 1.1;
            app.HueJitter_Probability = uispinner(app.IntensityandcolorPanel);
            app.HueJitter_Probability.Step = 0.05;
            app.HueJitter_Probability.Limits = [0 1];
            app.HueJitter_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.HueJitter_Probability.Tooltip = {'RGB ONLY: jitter of Hue using a random value in the range -1...1 (def=0.2; -0.03...0.03)'};
            app.HueJitter_Probability.Value = 0.2;
            app.HueJitter_Min = uispinner(app.IntensityandcolorPanel);
            app.HueJitter_Min.Step = 0.01;
            app.HueJitter_Min.Limits = [-1 0];
            app.HueJitter_Min.ValueDisplayFormat = '%11.3g';
            app.HueJitter_Min.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.HueJitter_Min.Tooltip = {'RGB ONLY: jitter of Hue using a random value in the range -1...1 (def=0.2; -0.03...0.03)'};
            app.HueJitter_Min.Value = -0.03;
            app.HueJitter_Max = uispinner(app.IntensityandcolorPanel);
            app.HueJitter_Max.Step = 0.01;
            app.HueJitter_Max.Limits = [0 Inf];
            app.HueJitter_Max.ValueDisplayFormat = '%11.3g';
            app.HueJitter_Max.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.HueJitter_Max.Tooltip = {'RGB ONLY: jitter of Hue using a random value in the range -1...1 (def=0.2; -0.03...0.03)'};
            app.HueJitter_Max.Value = 0.03;
            app.SaturationJitter_Probability = uispinner(app.IntensityandcolorPanel);
            app.SaturationJitter_Probability.Step = 0.05;
            app.SaturationJitter_Probability.Limits = [0 1];
            app.SaturationJitter_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.SaturationJitter_Probability.Tooltip = {'RGB ONLY: jitter of Saturation using a random value in the range -1...1 (def=0.2; -0.05...0.05)'};
            app.SaturationJitter_Probability.Value = 0.2;
            app.SaturationJitter_Min = uispinner(app.IntensityandcolorPanel);
            app.SaturationJitter_Min.Step = 0.01;
            app.SaturationJitter_Min.Limits = [-1 0];
            app.SaturationJitter_Min.ValueDisplayFormat = '%11.3g';
            app.SaturationJitter_Min.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.SaturationJitter_Min.Tooltip = {'RGB ONLY: jitter of Saturation using a random value in the range -1...1 (def=0.2; -0.05...0.05)'};
            app.SaturationJitter_Min.Value = -0.05;
            app.SaturationJitter_Max = uispinner(app.IntensityandcolorPanel);
            app.SaturationJitter_Max.Step = 0.01;
            app.SaturationJitter_Max.Limits = [0 1];
            app.SaturationJitter_Max.ValueDisplayFormat = '%11.3g';
            app.SaturationJitter_Max.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.SaturationJitter_Max.Tooltip = {'RGB ONLY: jitter of Saturation using a random value in the range -1...1 (def=0.2; -0.05...0.05)'};
            app.SaturationJitter_Max.Value = 0.05;
            app.NoiseandblurPanel = uipanel(app.middlePanel);
            app.NoiseandblurPanel.Title = 'Noise and blur';
            app.PoissonNoise_Enable = uicheckbox(app.NoiseandblurPanel);
            app.PoissonNoise_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.PoissonNoise_Enable.Tooltip = {'allow Poisson noise, (def=0.1)'};
            app.PoissonNoise_Enable.Text = 'PoissonNoise';
            app.PoissonNoise_Preview = uibutton(app.NoiseandblurPanel, 'push');
            app.PoissonNoise_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.PoissonNoise_Preview.Icon = 'eye.png';
            app.PoissonNoise_Preview.Tooltip = {'press to preview PoissonNoise augmentations'};
            app.PoissonNoise_Preview.Text = '';
            app.GaussianNoise_Enable = uicheckbox(app.NoiseandblurPanel);
            app.GaussianNoise_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.GaussianNoise_Enable.Tooltip = {'add Gaussian noise using a random variance in range 0... Inf (def=0.2, 0...0.005)'};
            app.GaussianNoise_Enable.Text = 'GaussianNoise';
            app.GaussianNoise_Preview = uibutton(app.NoiseandblurPanel, 'push');
            app.GaussianNoise_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.GaussianNoise_Preview.Icon = 'eye.png';
            app.GaussianNoise_Preview.Tooltip = {'press to preview GaussianNoise augmentations'};
            app.GaussianNoise_Preview.Text = '';
            app.ImageBlur_Enable = uicheckbox(app.NoiseandblurPanel);
            app.ImageBlur_Enable.ValueChangedFcn = createCallbackFcn(app, @enableStateChange, true);
            app.ImageBlur_Enable.Tooltip = {'allow Gaussian blur defined as sigma in the range 0...Inf (def=0.2; 0...0.5)'};
            app.ImageBlur_Enable.Text = 'ImageBlur';
            app.ImageBlur_Preview = uibutton(app.NoiseandblurPanel, 'push');
            app.ImageBlur_Preview.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.ImageBlur_Preview.Icon = 'eye.png';
            app.ImageBlur_Preview.Tooltip = {'press to preview ImageBlur  augmentations'};
            app.ImageBlur_Preview.Text = '';
            app.PoissonNoise_Probability = uispinner(app.NoiseandblurPanel);
            app.PoissonNoise_Probability.Step = 0.05;
            app.PoissonNoise_Probability.Limits = [0 1];
            app.PoissonNoise_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.PoissonNoise_Probability.Tooltip = {'allow Poisson noise, (def=0.1)'};
            app.PoissonNoise_Probability.Value = 0.05;
            app.GaussianNoise_Probability = uispinner(app.NoiseandblurPanel);
            app.GaussianNoise_Probability.Step = 0.05;
            app.GaussianNoise_Probability.Limits = [0 1];
            app.GaussianNoise_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.GaussianNoise_Probability.Tooltip = {'add Gaussian noise using a random variance in range 0... Inf (def=0.2, 0...0.005)'};
            app.GaussianNoise_Probability.Value = 0.1;
            app.GaussianNoise_Min = uispinner(app.NoiseandblurPanel);
            app.GaussianNoise_Min.Step = 0.001;
            app.GaussianNoise_Min.Limits = [0 Inf];
            app.GaussianNoise_Min.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.GaussianNoise_Min.Tooltip = {'add Gaussian noise using a random variance in range 0... Inf (def=0.2, 0...0.005)'};
            app.GaussianNoise_Max = uispinner(app.NoiseandblurPanel);
            app.GaussianNoise_Max.Step = 0.001;
            app.GaussianNoise_Max.Limits = [0 Inf];
            app.GaussianNoise_Max.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.GaussianNoise_Max.Tooltip = {'add Gaussian noise using a random variance in range 0... Inf (def=0.2, 0...0.005)'};
            app.GaussianNoise_Max.Value = 0.005;
            app.ImageBlur_Probability = uispinner(app.NoiseandblurPanel);
            app.ImageBlur_Probability.Step = 0.05;
            app.ImageBlur_Probability.Limits = [0 1];
            app.ImageBlur_Probability.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.ImageBlur_Probability.Tooltip = {'allow Gaussian blur defined as sigma in the range 0...Inf (def=0.2; 0...0.5)'};
            app.ImageBlur_Probability.Value = 0.1;
            app.ImageBlur_Min = uispinner(app.NoiseandblurPanel);
            app.ImageBlur_Min.Step = 0.1;
            app.ImageBlur_Min.Limits = [0 Inf];
            app.ImageBlur_Min.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.ImageBlur_Min.Tooltip = {'allow Gaussian blur defined as sigma in the range 0...Inf (def=0.2; 0...0.5)'};
            app.ImageBlur_Max = uispinner(app.NoiseandblurPanel);
            app.ImageBlur_Max.Step = 0.1;
            app.ImageBlur_Max.Limits = [0 Inf];
            app.ImageBlur_Max.Tooltip = {'allow Gaussian blur defined as sigma in the range 0...Inf (def=0.2; 0...0.5)'};
            app.ImageBlur_Max.Value = 0.5;
            app.Fraction = uispinner(app.middlePanel);
            app.Fraction.Step = 0.01;
            app.Fraction.Limits = [0 1];
            app.Fraction.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.Fraction.Tooltip = {'fraction of images to be augmented (def=1)'};
            app.Fraction.Value = 1;
            app.FillValue = uieditfield(app.middlePanel, 'numeric');
            app.FillValue.ValueChangedFcn = createCallbackFcn(app, @updateParameter, true);
            app.FillValue.Tooltip = {'fill value for out-of-bounds points when rotating [0-Inf, def=255]'};
            app.FillValue.Value = 255;
            app.bottomGridLayout = uigridlayout(app.mainGridLayout);
            app.SettingsforPreviewButton = uibutton(app.bottomGridLayout, 'push');
            app.SettingsforPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @setPreviewAugmentationSettings, true);
            app.SettingsforPreviewButton.Icon = 'settings.png';
            app.SettingsforPreviewButton.Tooltip = {'define settings for augmentation preview'};
            app.SettingsforPreviewButton.Text = 'Settings for Preview';
            app.PreviewButton = uibutton(app.bottomGridLayout, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @previewAugmentations, true);
            app.PreviewButton.Icon = 'eye.png';
            app.PreviewButton.Tooltip = {'preview all selected augmentations at once'};
            app.PreviewButton.Text = 'Preview';
            app.HelpButton = uibutton(app.bottomGridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.HelpButton.Text = 'Help';
            app.RandomseedEditFieldLabel = uilabel(app.bottomGridLayout);
            app.RandomseedEditFieldLabel.Tooltip = {'use this field to inialize the random seed generator. When 0, a random patch will be shown each time, when another value is used a fixed random patch is used'};
            app.RandomseedEditFieldLabel.Text = 'Random seed';
            app.RandomSeedEdit = uieditfield(app.bottomGridLayout, 'numeric');
            app.RandomSeedEdit.Limits = [0 Inf];
            app.RandomSeedEdit.RoundFractionalValues = 'on';
            app.RandomSeedEdit.ValueDisplayFormat = '%d';
            app.RandomSeedEdit.Tooltip = {'use this field to inialize the random seed generator. When 0, a random patch will be shown each time, when another value is used a fixed random patch is used'};
            app.PreviousseedButton = uibutton(app.bottomGridLayout, 'push');
            app.PreviousseedButton.ButtonPushedFcn = createCallbackFcn(app, @restorePreviousSeed, true);
            app.PreviousseedButton.Tooltip = {'press to restore a seed used during previous iteration of the random preview'};
            app.PreviousseedButton.Text = 'Previous seed';
            app.OK = uibutton(app.bottomGridLayout, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OK_Callback, true);
            app.OK.Tooltip = {'Accept augmentations and close the dialog'};
            app.OK.Text = 'OK';
            app.Close = uibutton(app.bottomGridLayout, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @Close_Callback, true);
            app.Close.Tooltip = {'Close the dialog without accepting the new set of augmentations'};
            app.Close.Text = 'Cancel';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mibDeepAugmentSettingsGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end