classdef mibDeepGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Mode                            matlab.ui.container.TabGroup
        Preprocess                      matlab.ui.container.Tab
        FractionofimagesforvalidationEditFieldLabel  matlab.ui.control.Label
        PreprocessforDropDownLabel      matlab.ui.control.Label
        DirectorywithimagesforpredictionLabel  matlab.ui.control.Label
        extensionLabel_2                matlab.ui.control.Label
        DirectorywithresultingimagesLabel  matlab.ui.control.Label
        DirectorywithimagesandmodelsfortrainingLabel  matlab.ui.control.Label
        extensionLabel                  matlab.ui.control.Label
        RandomgeneratorseedLabel        matlab.ui.control.Label
        WorkersEditFieldLabel           matlab.ui.control.Label
        ModelextensionDropDownLabel     matlab.ui.control.Label
        NumberofclassesEditFieldLabel_2  matlab.ui.control.Label
        ModelextensionDropDownLabel_2   matlab.ui.control.Label
        IndexEditFieldLabel             matlab.ui.control.Label
        IndexEditField_2Label           matlab.ui.control.Label
        PreprocessingMode               matlab.ui.control.DropDown
        PreprocessingParForWorkers      matlab.ui.control.NumericEditField
        UseParallelComputing            matlab.ui.control.CheckBox
        CompressProcessedModels         matlab.ui.control.CheckBox
        CompressProcessedImages         matlab.ui.control.CheckBox
        SelectResultingImagesDir        matlab.ui.control.Button
        NumberOfClassesPreprocessing    matlab.ui.control.NumericEditField
        MaskFilenameExtension           matlab.ui.control.DropDown
        ModelFilenameExtension          matlab.ui.control.DropDown
        MaskAway                        matlab.ui.control.CheckBox
        SingleModelTrainingFile         matlab.ui.control.CheckBox
        SelectOriginalPredictionImagesDir  matlab.ui.control.Button
        BioformatsIndex                 matlab.ui.control.NumericEditField
        Bioformats                      matlab.ui.control.CheckBox
        ImageFilenameExtension          matlab.ui.control.DropDown
        SelectOriginalTrainingImagesDir  matlab.ui.control.Button
        BioformatsTrainingIndex         matlab.ui.control.NumericEditField
        BioformatsTraining              matlab.ui.control.CheckBox
        ImageFilenameExtensionTraining  matlab.ui.control.DropDown
        OriginalTrainingImagesDir       matlab.ui.control.EditField
        OriginalPredictionImagesDir     matlab.ui.control.EditField
        ResultingImagesDir              matlab.ui.control.EditField
        ValidationFraction              matlab.ui.control.NumericEditField
        RandomGeneratorSeed             matlab.ui.control.NumericEditField
        SettingsLabel                   matlab.ui.control.Label
        Train                           matlab.ui.container.Tab
        NetworkDesignPanel              matlab.ui.container.Panel
        InputpatchsizeLabel             matlab.ui.control.Label
        T_InputPatchSize                matlab.ui.control.EditField
        NumberofclassesEditFieldLabel   matlab.ui.control.Label
        T_NumberOfClasses               matlab.ui.control.NumericEditField
        EncoderdepthLabel               matlab.ui.control.Label
        T_EncoderDepth                  matlab.ui.control.NumericEditField
        PaddingLabel                    matlab.ui.control.Label
        T_ConvolutionPadding            matlab.ui.control.DropDown
        InputLayerSettings              matlab.ui.control.Button
        T_ActivationLayerSettings       matlab.ui.control.Button
        T_SegmentationLayerSettings     matlab.ui.control.Button
        SegmentationlayerDropDownLabel  matlab.ui.control.Label
        T_SegmentationLayer             matlab.ui.control.DropDown
        FiltersLabel                    matlab.ui.control.Label
        T_NumFirstEncoderFilters        matlab.ui.control.NumericEditField
        FiltersizeLabel                 matlab.ui.control.Label
        T_FilterSize                    matlab.ui.control.NumericEditField
        ActivationlayerLabel            matlab.ui.control.Label
        T_ActivationLayer               matlab.ui.control.DropDown
        CheckNetwork                    matlab.ui.control.Button
        T_UseImageNetWeights            matlab.ui.control.CheckBox
        AugmentationDesignPanel         matlab.ui.container.Panel
        Augmentation2DSettings          matlab.ui.control.Button
        Augmentation3DSettings          matlab.ui.control.Button
        T_AugmentationPreview           matlab.ui.control.Button
        T_AugmentationPreviewSettings   matlab.ui.control.Button
        T_augmentation                  matlab.ui.control.CheckBox
        TrainingProcessDesignPanel      matlab.ui.container.Panel
        PatchesperimageLabel            matlab.ui.control.Label
        T_PatchesPerImage               matlab.ui.control.NumericEditField
        MiniBatchSizeLabel              matlab.ui.control.Label
        T_MiniBatchSize                 matlab.ui.control.NumericEditField
        TrainSettings                   matlab.ui.control.Button
        T_SaveProgress                  matlab.ui.control.CheckBox
        T_ExportTrainingPlots           matlab.ui.control.CheckBox
        T_RandomgeneratorseedLabel      matlab.ui.control.Label
        T_RandomGeneratorSeed           matlab.ui.control.NumericEditField
        BetaVersionPanel                matlab.ui.container.Panel
        T_DownsamplingLabel             matlab.ui.control.Label
        T_DownsamplingFactor            matlab.ui.control.NumericEditField
        TransferLearning                matlab.ui.control.Button
        Predict                         matlab.ui.container.Tab
        PreviewresultsPanel             matlab.ui.container.Panel
        loadImagesAndModels             matlab.ui.control.Button
        LoadPredictions                 matlab.ui.control.Button
        PreviewResultsText              matlab.ui.control.Label
        PreviewResultsText2             matlab.ui.control.Label
        EvaluateSegmentation            matlab.ui.control.Button
        loadModels                      matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        P_OverlappingTiles              matlab.ui.control.CheckBox
        P_DynamicMasking                matlab.ui.control.CheckBox
        P_DynamicMaskSettings           matlab.ui.control.Button
        previewDynamicMask              matlab.ui.control.Button
        BatchsizeforpredictionLabel     matlab.ui.control.Label
        P_MiniBatchSize                 matlab.ui.control.NumericEditField
        ScorefilesDropDownLabel         matlab.ui.control.Label
        P_ScoreFiles                    matlab.ui.control.DropDown
        ModelfilesLabel                 matlab.ui.control.Label
        P_ModelFiles                    matlab.ui.control.DropDown
        PredictionengineLabel           matlab.ui.control.Label
        P_PredictionMode                matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        P_OverlappingTilesPercentage    matlab.ui.control.NumericEditField
        ExploreActivations              matlab.ui.control.Button
        PatchwisesettingsPanel          matlab.ui.container.Panel
        P_PatchWiseUpsample             matlab.ui.control.CheckBox
        Options                         matlab.ui.container.Tab
        ConfigfilesPanel                matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ConfigFilesLabel                matlab.ui.control.Label
        CustomtrainingplotPanel         matlab.ui.container.Panel
        O_CustomTrainingProgressWindow  matlab.ui.control.CheckBox
        RefreshrateiterLabel            matlab.ui.control.Label
        NumberofpointsLabel             matlab.ui.control.Label
        O_PreviewImagePatches           matlab.ui.control.CheckBox
        FractionofimagesforpreviewLabel  matlab.ui.control.Label
        O_RefreshRateIter               matlab.ui.control.NumericEditField
        O_NumberOfPoints                matlab.ui.control.NumericEditField
        O_FractionOfPreviewPatches      matlab.ui.control.NumericEditField
        OptionsToolsPanel               matlab.ui.container.Panel
        O_exportNetworkToONNXButton     matlab.ui.control.Button
        O_countLabelsButton             matlab.ui.control.Button
        O_importNetworkButton           matlab.ui.control.Button
        O_balanceClassesButton          matlab.ui.control.Button
        AugmentationsPanel              matlab.ui.container.Panel
        reset2DAugmentations            matlab.ui.control.Button
        disable2DAugmentations          matlab.ui.control.Button
        reset3DAugmentations            matlab.ui.control.Button
        disable3DAugmentations          matlab.ui.control.Button
        Aug2DLabel                      matlab.ui.control.Label
        Aug3DLabel                      matlab.ui.control.Label
        NetworkPanel                    matlab.ui.container.Panel
        NetworkfilenameEditFieldLabel   matlab.ui.control.Label
        WorkflowLabel                   matlab.ui.control.Label
        GPULabel_2                      matlab.ui.control.Label
        NetworkFilename                 matlab.ui.control.EditField
        SelectNetwork                   matlab.ui.control.Button
        gpuInfoBtn                      matlab.ui.control.Button
        GPUDropDown                     matlab.ui.control.DropDown
        Workflow                        matlab.ui.control.DropDown
        ArchitectureLabel_2             matlab.ui.control.Label
        Architecture                    matlab.ui.control.DropDown
        BottomButtonsPanel              matlab.ui.container.Panel
        TrainButton                     matlab.ui.control.Button
        Help                            matlab.ui.control.Button
        PreprocessButton                matlab.ui.control.Button
        PredictButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        winController % controller of the view % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = 'off';
            app.ConfigFilesLabel.Text = sprintf('Config files are automatically\ncreated before training and\ninclude all DeepMIB settings\nbut without the trained network');
            app.PreviewResultsText.Text = sprintf('Use these buttons to preview predictions \nusing the currently active container of MIB.\nFor 3D networks only the first dataset will be shown');
            app.PreviewResultsText2.Text = sprintf('Press to evaluate results of the segmentation; requires\nground truth *.model files next to datasets for prediction');
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
            global mibPath;
            web(fullfile(mibPath, 'techdoc', 'html', 'ug_gui_menu_tools_deeplearning.html'), '-helpbrowser');
        end

        % Callback function: NetworkFilename, SelectNetwork
        function SelectNetworkButtonPushed(app, event)
            if strcmp(event.Source.Tag, 'NetworkFilename')
                app.winController.selectNetwork(event.Source.Value);   % callback from editbox change 
            else
                app.winController.selectNetwork();    
            end

        end

        % Value changed function: BioformatsIndex, 
        % BioformatsTrainingIndex, CompressProcessedImages, 
        % CompressProcessedModels, ImageFilenameExtension, 
        % ImageFilenameExtensionTraining, MaskAway, 
        % MaskFilenameExtension, ModelFilenameExtension, 
        % O_FractionOfPreviewPatches, O_NumberOfPoints, 
        % O_RefreshRateIter, P_DynamicMasking, P_MiniBatchSize, 
        % P_ModelFiles, P_OverlappingTiles, 
        % P_OverlappingTilesPercentage, P_PatchWiseUpsample, 
        % P_PredictionMode, P_ScoreFiles, RandomGeneratorSeed, 
        % T_ConvolutionPadding, T_EncoderDepth, 
        % T_ExportTrainingPlots, T_FilterSize, T_MiniBatchSize, 
        % T_NumFirstEncoderFilters, T_PatchesPerImage, 
        % T_RandomGeneratorSeed, T_UseImageNetWeights, 
        % ValidationFraction
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: 
        % SelectOriginalPredictionImagesDir, 
        % SelectOriginalTrainingImagesDir, SelectResultingImagesDir
        function SelectDirerctories(app, event)
            app.winController.selectDirerctories(event);
        end

        % Value changed function: OriginalPredictionImagesDir, 
        % OriginalTrainingImagesDir, ResultingImagesDir
        function ImageDirectoriesValueChanged(app, event)
            app.winController.ImageDirectoriesValueChanged(event);
            
        end

        % Button pushed function: PredictButton, PreprocessButton, 
        % TrainButton
        function StartButtonPushed(app, event)
            app.winController.Start(event);
        end

        % Button pushed function: CheckNetwork
        function CheckNetworkButtonPushed(app, event)
            app.winController.CheckNetwork();
        end

        % Button pushed function: TrainSettings
        function TrainSettingsButtonPushed(app, event)
            app.winController.setTrainingSettings();
        end

        % Selection change function: Mode
        function ModeSelectionChanged(app, event)
            selectedTab = app.Mode.SelectedTab;
            app.SelectNetwork.Enable = 'off';
            app.TrainButton.Enable = 'off';
            app.PredictButton.Enable = 'off';
            app.PreprocessButton.Enable = 'off';
            app.PreprocessButton.BackgroundColor = app.NetworkPanel.BackgroundColor;
            app.TrainButton.BackgroundColor = app.NetworkPanel.BackgroundColor;
            app.PredictButton.BackgroundColor = app.NetworkPanel.BackgroundColor;
            app.SelectNetwork.BackgroundColor = app.NetworkPanel.BackgroundColor;
            switch selectedTab.Tag
                case 'Preprocess'
                    app.PreprocessButton.Enable = 'on';
                    app.PreprocessButton.BackgroundColor = app.Preprocess.BackgroundColor;
                    app.SelectNetwork.Enable = 'on';
                    app.NetworkFilename.Enable = 'on';
                case 'Train'
                    app.SelectNetwork.Enable = 'on';
                    app.SelectNetwork.BackgroundColor = app.Train.BackgroundColor;
                    app.TrainButton.BackgroundColor = app.Train.BackgroundColor;
                    app.TrainButton.Enable = 'on';
                    app.NetworkFilename.Enable = 'on';
                case 'Predict'
                    app.SelectNetwork.Enable = 'on';
                    app.SelectNetwork.BackgroundColor = app.Predict.BackgroundColor;
                    app.PredictButton.BackgroundColor = app.Predict.BackgroundColor;
                    app.PredictButton.Enable = 'on';
                    app.NetworkFilename.Enable = 'on';
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: Augmentation2DSettings, 
        % Augmentation3DSettings
        function AugmentationSettingsButtonPushed(app, event)
            switch event.Source.Tag
                case 'Augmentation2DSettings'
                    app.winController.setAugmentation2DSettings();
                case 'Augmentation3DSettings'
                    app.winController.setAugmentation3DSettings();
            end
        end

        % Value changed function: T_augmentation
        function T_augmentationValueChanged(app, event)
            app.winController.T_augmentationCallback();
        end

        % Value changed function: T_InputPatchSize
        function T_InputPatchSizeValueChanged(app, event)
            value = app.T_InputPatchSize.Value;
            value = str2num(value); %#ok<ST2NM> 
            if numel(value) ~= 4
                errordlg(sprintf('!!! Error !!!\n\nPlease provide the input patch size as 4 numbers that define\nheight, width, depth, colors\n\nFor example:\n"32, 32, 1, 3" for 2D U-net of 3 color channel images\n"64, 64, 64, 1" for 3D U-net of 1 color channel images'), ...
                    'Wrong patch size');
                return;
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: InputLayerSettings
        function InputLayerSettingsButtonPushed(app, event)
            app.winController.setInputLayerSettings();    
        end

        % Button pushed function: LoadPredictions
        function LoadPredictionsButtonPushed(app, event)
            app.winController.previewPredictions(); 
        end

        % Button pushed function: loadImagesAndModels, loadModels
        function loadImagesAndModelsButtonPushed(app, event)
            switch event.Source.Tag
                case 'loadImagesAndModels'
                    loadImagesSwitch = true;
                    %loadModelsSwitch = true;
                case 'loadModels'
                    loadImagesSwitch = false;
                    %loadModelsSwitch = true;
            end    
            app.winController.previewModels(loadImagesSwitch);
        end

        % Button pushed function: EvaluateSegmentation
        function EvaluateSegmentationButtonPushed(app, event)
            app.winController.EvaluateSegmentation(); 
        end

        % Button pushed function: ExploreActivations
        function ExploreActivationsButtonPushed(app, event)
            app.winController.ExploreActivations();
        end

        % Value changed function: Bioformats, BioformatsTraining
        function BioformatsValueChanged(app, event)
            app.winController.updateBatchOptFromGUI(event);
            app.winController.BioformatsCallback(event);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.winController.loadConfig();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.winController.saveConfig();
        end

        % Value changed function: GPUDropDown
        function GPUDropDownValueChanged(app, event)
            app.winController.selectGPUDevice();
        end

        % Button pushed function: gpuInfoBtn
        function gpuInfoBtnButtonPushed(app, event)
            app.winController.gpuInfo();
        end

        % Button pushed function: TransferLearning
        function TransferLearningButtonPushed(app, event)
            app.winController.transferLearning();
        end

        % Value changed function: UseParallelComputing
        function UseParallelComputingValueChanged(app, event)
            if app.UseParallelComputing.Value
                app.PreprocessingParForWorkers.Enable = 'on';
            else
                app.PreprocessingParForWorkers.Enable = 'off';
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: O_exportNetworkToONNXButton
        function O_exportNetworkToONNXButtonPushed(app, event)
            app.winController.ExportNetworkToONNXButton();
        end

        % Value changed function: SingleModelTrainingFile
        function SingleModelTrainingFileValueChanged(app, event)
            app.winController.SingleModelTrainingFileValueChanged(event);
        end

        % Value changed function: Workflow
        function WorkflowValueChanged(app, event)
            app.winController.selectWorkflow(event);
        end

        % Value changed function: NumberOfClassesPreprocessing
        function NumberOfClassesPreprocessingValueChanged(app, event)
            app.T_NumberOfClasses.Value = app.NumberOfClassesPreprocessing.Value;
            event2.Source = app.T_NumberOfClasses;
            app.T_NumberOfClassesValueChanged(event2);
        end

        % Value changed function: T_NumberOfClasses
        function T_NumberOfClassesValueChanged(app, event)
            app.NumberOfClassesPreprocessing.Value = app.T_NumberOfClasses.Value;
            app.winController.updateBatchOptFromGUI(event);
        end

        % Value changed function: T_ActivationLayer
        function T_ActivationLayerValueChanged(app, event)
            app.winController.T_ActivationLayerCallback();
        end

        % Value changed function: O_CustomTrainingProgressWindow
        function O_CustomTrainingProgressWindowValueChanged(app, event)
            if app.O_CustomTrainingProgressWindow.Value
                app.O_RefreshRateIter.Enable = 'on';
                app.O_NumberOfPoints.Enable = 'on';
                app.O_PreviewImagePatches.Enable = 'on';
                app.O_FractionOfPreviewPatches.Enable = 'on';
            else
                app.O_RefreshRateIter.Enable = 'off';
                app.O_NumberOfPoints.Enable = 'off';
                app.O_PreviewImagePatches.Enable = 'off';
                app.O_FractionOfPreviewPatches.Enable = 'off';
            end
            app.winController.updateBatchOptFromGUI(event);
            event2.Source = app.O_PreviewImagePatches;
            app.O_PreviewImagePatchesValueChanged(event2);
        end

        % Value changed function: O_PreviewImagePatches
        function O_PreviewImagePatchesValueChanged(app, event)
            if app.O_PreviewImagePatches.Value && strcmp(app.O_PreviewImagePatches.Enable, 'on')
                app.O_FractionOfPreviewPatches.Enable = 'on';
            else
                app.O_FractionOfPreviewPatches.Enable = 'off';
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: reset2DAugmentations, 
        % reset3DAugmentations
        function resetAugmentations(app, event)
            switch event.Source.Tag
                case 'reset2DAugmentations'
                    app.winController.resetAugmentations('2D');
                case 'reset3DAugmentations'
                    app.winController.resetAugmentations('3D');
            end
        end

        % Button pushed function: T_AugmentationPreview
        function T_AugmentationPreviewButtonPushed(app, event)
            app.winController.previewAugmentations();
        end

        % Button pushed function: T_AugmentationPreviewSettings
        function T_AugmentationPreviewSettingsButtonPushed(app, event)
            app.winController.setPreviewAugmentationSettings();
        end

        % Value changed function: PreprocessingMode
        function PreprocessingModeValueChanged(app, event)
            app.winController.updatePreprocessingMode();
        end

        % Value changed function: T_SegmentationLayer
        function T_SegmentationLayerValueChanged(app, event)
            app.winController.T_SegmentationLayerCallback();
        end

        % Button pushed function: T_ActivationLayerSettings
        function T_ActivationLayerSettingsButtonPushed(app, event)
            app.winController.setActivationLayerOptions();
        end

        % Button pushed function: T_SegmentationLayerSettings
        function T_SegmentationLayerSettingsButtonPushed(app, event)
            app.winController.setSegmentationLayerOptions();
        end

        % Button pushed function: disable2DAugmentations, 
        % disable3DAugmentations
        function disableAugmentations(app, event)
            switch event.Source.Tag
                case 'disable2DAugmentations'
                    app.winController.disableAugmentations('2D');
                case 'disable3DAugmentations'
                    app.winController.disableAugmentations('3D');
            end
        end

        % Button pushed function: O_countLabelsButton
        function O_countLabelsButtonPushed(app, event)
            app.winController.countLabels()
        end

        % Button pushed function: O_importNetworkButton
        function O_importNetworkButtonPushed(app, event)
            app.winController.importNetwork()
        end

        % Button pushed function: P_DynamicMaskSettings
        function P_DynamicMaskSettingsButtonPushed(app, event)
            app.winController.updateDynamicMaskSettings()
        end

        % Value changed function: Architecture
        function ArchitectureValueChanged(app, event)
            app.winController.selectArchitecture(event);
        end

        % Button pushed function: previewDynamicMask
        function previewDynamicMaskButtonPushed(app, event)
            app.winController.previewDynamicMask();
        end

        % Value changed function: T_SaveProgress
        function T_SaveProgressValueChanged(app, event)
            app.winController.saveCheckpointNetworkCheck();
        end

        % Button pushed function: O_balanceClassesButton
        function O_balanceClassesButtonPushed(app, event)
            app.winController.balanceClasses();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'DeepMIB';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.Figure);
            app.Mode = uitabgroup(app.GridLayout);
            app.Mode.SelectionChangedFcn = createCallbackFcn(app, @ModeSelectionChanged, true);
            app.Preprocess = uitab(app.Mode);
            app.Preprocess.Title = 'Directories and Preprocessing';
            app.FractionofimagesforvalidationEditFieldLabel = uilabel(app.Preprocess);
            app.FractionofimagesforvalidationEditFieldLabel.Text = 'Fraction of images for validation';
            app.PreprocessforDropDownLabel = uilabel(app.Preprocess);
            app.PreprocessforDropDownLabel.Text = 'Preprocess for';
            app.DirectorywithimagesforpredictionLabel = uilabel(app.Preprocess);
            app.DirectorywithimagesforpredictionLabel.Text = 'Directory with images for prediction';
            app.extensionLabel_2 = uilabel(app.Preprocess);
            app.extensionLabel_2.Text = 'extension';
            app.DirectorywithresultingimagesLabel = uilabel(app.Preprocess);
            app.DirectorywithresultingimagesLabel.Text = 'Directory with resulting images';
            app.DirectorywithimagesandmodelsfortrainingLabel = uilabel(app.Preprocess);
            app.DirectorywithimagesandmodelsfortrainingLabel.Text = 'Directory with images and models for training';
            app.extensionLabel = uilabel(app.Preprocess);
            app.extensionLabel.Text = 'extension';
            app.RandomgeneratorseedLabel = uilabel(app.Preprocess);
            app.RandomgeneratorseedLabel.Text = 'Random generator seed';
            app.WorkersEditFieldLabel = uilabel(app.Preprocess);
            app.WorkersEditFieldLabel.Text = 'Workers';
            app.ModelextensionDropDownLabel = uilabel(app.Preprocess);
            app.ModelextensionDropDownLabel.Text = 'Model extension';
            app.NumberofclassesEditFieldLabel_2 = uilabel(app.Preprocess);
            app.NumberofclassesEditFieldLabel_2.Text = 'Number of classes';
            app.ModelextensionDropDownLabel_2 = uilabel(app.Preprocess);
            app.ModelextensionDropDownLabel_2.Text = 'Mask extension';
            app.IndexEditFieldLabel = uilabel(app.Preprocess);
            app.IndexEditFieldLabel.Text = 'Index';
            app.IndexEditField_2Label = uilabel(app.Preprocess);
            app.IndexEditField_2Label.Text = 'Index';
            app.PreprocessingMode = uidropdown(app.Preprocess);
            app.PreprocessingMode.ValueChangedFcn = createCallbackFcn(app, @PreprocessingModeValueChanged, true);
            app.PreprocessingParForWorkers = uieditfield(app.Preprocess, 'numeric');
            app.PreprocessingParForWorkers.Limits = [1 Inf];
            app.PreprocessingParForWorkers.Tooltip = {'define number of workers for parallel processsing'};
            app.PreprocessingParForWorkers.Value = 1;
            app.UseParallelComputing = uicheckbox(app.Preprocess);
            app.UseParallelComputing.ValueChangedFcn = createCallbackFcn(app, @UseParallelComputingValueChanged, true);
            app.UseParallelComputing.Tooltip = {'enable parallel computing during image preprocessing'};
            app.UseParallelComputing.Text = 'Use parallel processing';
            app.CompressProcessedModels = uicheckbox(app.Preprocess);
            app.CompressProcessedModels.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.CompressProcessedModels.Text = 'Compress processed models';
            app.CompressProcessedImages = uicheckbox(app.Preprocess);
            app.CompressProcessedImages.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.CompressProcessedImages.Text = 'Compress processed images';
            app.SelectResultingImagesDir = uibutton(app.Preprocess, 'push');
            app.SelectResultingImagesDir.ButtonPushedFcn = createCallbackFcn(app, @SelectDirerctories, true);
            app.SelectResultingImagesDir.Tooltip = {'Select the output directory for results'};
            app.SelectResultingImagesDir.Text = '...';
            app.NumberOfClassesPreprocessing = uieditfield(app.Preprocess, 'numeric');
            app.NumberOfClassesPreprocessing.Limits = [1 255];
            app.NumberOfClassesPreprocessing.RoundFractionalValues = 'on';
            app.NumberOfClassesPreprocessing.ValueChangedFcn = createCallbackFcn(app, @NumberOfClassesPreprocessingValueChanged, true);
            app.NumberOfClassesPreprocessing.Enable = 'off';
            app.NumberOfClassesPreprocessing.Tooltip = {'Number of classes including exterior'};
            app.NumberOfClassesPreprocessing.Value = 1;
            app.MaskFilenameExtension = uidropdown(app.Preprocess);
            app.MaskFilenameExtension.Items = {'MASK', 'AM', 'PNG', 'TIF'};
            app.MaskFilenameExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.MaskFilenameExtension.Enable = 'off';
            app.MaskFilenameExtension.Value = 'MASK';
            app.ModelFilenameExtension = uidropdown(app.Preprocess);
            app.ModelFilenameExtension.Items = {'MODEL', 'AM', 'PNG', 'TIF'};
            app.ModelFilenameExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ModelFilenameExtension.Value = 'MODEL';
            app.MaskAway = uicheckbox(app.Preprocess);
            app.MaskAway.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.MaskAway.Text = 'Use masking';
            app.SingleModelTrainingFile = uicheckbox(app.Preprocess);
            app.SingleModelTrainingFile.ValueChangedFcn = createCallbackFcn(app, @SingleModelTrainingFileValueChanged, true);
            app.SingleModelTrainingFile.Text = 'Single MIB model file';
            app.SelectOriginalPredictionImagesDir = uibutton(app.Preprocess, 'push');
            app.SelectOriginalPredictionImagesDir.ButtonPushedFcn = createCallbackFcn(app, @SelectDirerctories, true);
            app.SelectOriginalPredictionImagesDir.Tooltip = {'Select directory with original images to be used for prediction'};
            app.SelectOriginalPredictionImagesDir.Text = '...';
            app.BioformatsIndex = uieditfield(app.Preprocess, 'numeric');
            app.BioformatsIndex.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.Bioformats = uicheckbox(app.Preprocess);
            app.Bioformats.ValueChangedFcn = createCallbackFcn(app, @BioformatsValueChanged, true);
            app.Bioformats.Text = 'Bio';
            app.ImageFilenameExtension = uidropdown(app.Preprocess);
            app.ImageFilenameExtension.Items = {'TIF', 'Option 2', 'Option 3', 'Option 4'};
            app.ImageFilenameExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ImageFilenameExtension.Value = 'TIF';
            app.SelectOriginalTrainingImagesDir = uibutton(app.Preprocess, 'push');
            app.SelectOriginalTrainingImagesDir.ButtonPushedFcn = createCallbackFcn(app, @SelectDirerctories, true);
            app.SelectOriginalTrainingImagesDir.Tooltip = {'Select directory with original images and models. The images and models should be placed under "Images" and "Labels" subfolders correspondingly'};
            app.SelectOriginalTrainingImagesDir.Text = '...';
            app.BioformatsTrainingIndex = uieditfield(app.Preprocess, 'numeric');
            app.BioformatsTrainingIndex.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.BioformatsTraining = uicheckbox(app.Preprocess);
            app.BioformatsTraining.ValueChangedFcn = createCallbackFcn(app, @BioformatsValueChanged, true);
            app.BioformatsTraining.Text = 'Bio';
            app.ImageFilenameExtensionTraining = uidropdown(app.Preprocess);
            app.ImageFilenameExtensionTraining.Items = {'TIF', 'Option 2', 'Option 3', 'Option 4'};
            app.ImageFilenameExtensionTraining.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ImageFilenameExtensionTraining.Value = 'TIF';
            app.OriginalTrainingImagesDir = uieditfield(app.Preprocess, 'text');
            app.OriginalTrainingImagesDir.ValueChangedFcn = createCallbackFcn(app, @ImageDirectoriesValueChanged, true);
            app.OriginalPredictionImagesDir = uieditfield(app.Preprocess, 'text');
            app.OriginalPredictionImagesDir.ValueChangedFcn = createCallbackFcn(app, @ImageDirectoriesValueChanged, true);
            app.ResultingImagesDir = uieditfield(app.Preprocess, 'text');
            app.ResultingImagesDir.ValueChangedFcn = createCallbackFcn(app, @ImageDirectoriesValueChanged, true);
            app.ValidationFraction = uieditfield(app.Preprocess, 'numeric');
            app.ValidationFraction.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ValidationFraction.Value = 0.25;
            app.RandomGeneratorSeed = uieditfield(app.Preprocess, 'numeric');
            app.RandomGeneratorSeed.Limits = [0 Inf];
            app.RandomGeneratorSeed.RoundFractionalValues = 'on';
            app.RandomGeneratorSeed.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.RandomGeneratorSeed.Value = 2;
            app.SettingsLabel = uilabel(app.Preprocess);
            app.SettingsLabel.Text = 'Settings';
            app.Train = uitab(app.Mode);
            app.Train.Title = 'Train';
            app.NetworkDesignPanel = uipanel(app.Train);
            app.NetworkDesignPanel.Title = 'Network design';
            app.InputpatchsizeLabel = uilabel(app.NetworkDesignPanel);
            app.InputpatchsizeLabel.Text = 'Input patch size';
            app.T_InputPatchSize = uieditfield(app.NetworkDesignPanel, 'text');
            app.T_InputPatchSize.ValueChangedFcn = createCallbackFcn(app, @T_InputPatchSizeValueChanged, true);
            app.T_InputPatchSize.Value = '64 64 32';
            app.NumberofclassesEditFieldLabel = uilabel(app.NetworkDesignPanel);
            app.NumberofclassesEditFieldLabel.Text = 'Number of classes';
            app.T_NumberOfClasses = uieditfield(app.NetworkDesignPanel, 'numeric');
            app.T_NumberOfClasses.ValueChangedFcn = createCallbackFcn(app, @T_NumberOfClassesValueChanged, true);
            app.T_NumberOfClasses.Value = 3;
            app.EncoderdepthLabel = uilabel(app.NetworkDesignPanel);
            app.EncoderdepthLabel.Text = 'Encoder depth';
            app.T_EncoderDepth = uieditfield(app.NetworkDesignPanel, 'numeric');
            app.T_EncoderDepth.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_EncoderDepth.Value = 3;
            app.PaddingLabel = uilabel(app.NetworkDesignPanel);
            app.PaddingLabel.Text = 'Padding';
            app.T_ConvolutionPadding = uidropdown(app.NetworkDesignPanel);
            app.T_ConvolutionPadding.Items = {'same', 'valid'};
            app.T_ConvolutionPadding.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_ConvolutionPadding.Value = 'same';
            app.InputLayerSettings = uibutton(app.NetworkDesignPanel, 'push');
            app.InputLayerSettings.ButtonPushedFcn = createCallbackFcn(app, @InputLayerSettingsButtonPushed, true);
            app.InputLayerSettings.Icon = 'settings.png';
            app.InputLayerSettings.Tooltip = {'Define training parameters'; ''};
            app.InputLayerSettings.Text = 'Input layer';
            app.T_ActivationLayerSettings = uibutton(app.NetworkDesignPanel, 'push');
            app.T_ActivationLayerSettings.ButtonPushedFcn = createCallbackFcn(app, @T_ActivationLayerSettingsButtonPushed, true);
            app.T_ActivationLayerSettings.Icon = 'settings.png';
            app.T_ActivationLayerSettings.Tooltip = {'define settings for preview of the augmented patches'; ''};
            app.T_ActivationLayerSettings.Text = '';
            app.T_SegmentationLayerSettings = uibutton(app.NetworkDesignPanel, 'push');
            app.T_SegmentationLayerSettings.ButtonPushedFcn = createCallbackFcn(app, @T_SegmentationLayerSettingsButtonPushed, true);
            app.T_SegmentationLayerSettings.Icon = 'settings.png';
            app.T_SegmentationLayerSettings.Tooltip = {'define settings for the segmentation layer'; ''};
            app.T_SegmentationLayerSettings.Text = '';
            app.SegmentationlayerDropDownLabel = uilabel(app.NetworkDesignPanel);
            app.SegmentationlayerDropDownLabel.Text = 'Segmentation layer';
            app.T_SegmentationLayer = uidropdown(app.NetworkDesignPanel);
            app.T_SegmentationLayer.ValueChangedFcn = createCallbackFcn(app, @T_SegmentationLayerValueChanged, true);
            app.FiltersLabel = uilabel(app.NetworkDesignPanel);
            app.FiltersLabel.Text = 'Filters';
            app.T_NumFirstEncoderFilters = uieditfield(app.NetworkDesignPanel, 'numeric');
            app.T_NumFirstEncoderFilters.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_NumFirstEncoderFilters.Value = 32;
            app.FiltersizeLabel = uilabel(app.NetworkDesignPanel);
            app.FiltersizeLabel.Text = 'Filter size';
            app.T_FilterSize = uieditfield(app.NetworkDesignPanel, 'numeric');
            app.T_FilterSize.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_FilterSize.Value = 3;
            app.ActivationlayerLabel = uilabel(app.NetworkDesignPanel);
            app.ActivationlayerLabel.Text = 'Activation layer';
            app.T_ActivationLayer = uidropdown(app.NetworkDesignPanel);
            app.T_ActivationLayer.ValueChangedFcn = createCallbackFcn(app, @T_ActivationLayerValueChanged, true);
            app.CheckNetwork = uibutton(app.NetworkDesignPanel, 'push');
            app.CheckNetwork.ButtonPushedFcn = createCallbackFcn(app, @CheckNetworkButtonPushed, true);
            app.CheckNetwork.Text = 'Check network';
            app.T_UseImageNetWeights = uicheckbox(app.NetworkDesignPanel);
            app.T_UseImageNetWeights.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_UseImageNetWeights.Enable = 'off';
            app.T_UseImageNetWeights.Text = 'use ImageNet weights';
            app.AugmentationDesignPanel = uipanel(app.Train);
            app.AugmentationDesignPanel.Title = 'Augmentation design';
            app.Augmentation2DSettings = uibutton(app.AugmentationDesignPanel, 'push');
            app.Augmentation2DSettings.ButtonPushedFcn = createCallbackFcn(app, @AugmentationSettingsButtonPushed, true);
            app.Augmentation2DSettings.Icon = 'settings.png';
            app.Augmentation2DSettings.Tooltip = {'set settings for 2D augmentation'};
            app.Augmentation2DSettings.Text = '2D';
            app.Augmentation3DSettings = uibutton(app.AugmentationDesignPanel, 'push');
            app.Augmentation3DSettings.ButtonPushedFcn = createCallbackFcn(app, @AugmentationSettingsButtonPushed, true);
            app.Augmentation3DSettings.Icon = 'settings.png';
            app.Augmentation3DSettings.Tooltip = {'set settings for 3D augmentation'; ''};
            app.Augmentation3DSettings.Text = '3D';
            app.T_AugmentationPreview = uibutton(app.AugmentationDesignPanel, 'push');
            app.T_AugmentationPreview.ButtonPushedFcn = createCallbackFcn(app, @T_AugmentationPreviewButtonPushed, true);
            app.T_AugmentationPreview.Tooltip = {'preview random augmentations of a patch'};
            app.T_AugmentationPreview.Text = 'Preview';
            app.T_AugmentationPreviewSettings = uibutton(app.AugmentationDesignPanel, 'push');
            app.T_AugmentationPreviewSettings.ButtonPushedFcn = createCallbackFcn(app, @T_AugmentationPreviewSettingsButtonPushed, true);
            app.T_AugmentationPreviewSettings.Icon = 'settings.png';
            app.T_AugmentationPreviewSettings.Tooltip = {'define settings for preview of the augmented patches'; ''};
            app.T_AugmentationPreviewSettings.Text = '';
            app.T_augmentation = uicheckbox(app.AugmentationDesignPanel);
            app.T_augmentation.ValueChangedFcn = createCallbackFcn(app, @T_augmentationValueChanged, true);
            app.T_augmentation.Tooltip = {'enable data augmentation'};
            app.T_augmentation.Text = 'augmentation';
            app.TrainingProcessDesignPanel = uipanel(app.Train);
            app.TrainingProcessDesignPanel.Title = 'Training process design';
            app.PatchesperimageLabel = uilabel(app.TrainingProcessDesignPanel);
            app.PatchesperimageLabel.Text = 'Patches per image';
            app.T_PatchesPerImage = uieditfield(app.TrainingProcessDesignPanel, 'numeric');
            app.T_PatchesPerImage.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_PatchesPerImage.Value = 32;
            app.MiniBatchSizeLabel = uilabel(app.TrainingProcessDesignPanel);
            app.MiniBatchSizeLabel.Text = 'Mini Batch Size';
            app.T_MiniBatchSize = uieditfield(app.TrainingProcessDesignPanel, 'numeric');
            app.T_MiniBatchSize.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_MiniBatchSize.Tooltip = {'Random seed generator for consistent training, use 0 for random initialization'};
            app.T_MiniBatchSize.Value = 4;
            app.TrainSettings = uibutton(app.TrainingProcessDesignPanel, 'push');
            app.TrainSettings.ButtonPushedFcn = createCallbackFcn(app, @TrainSettingsButtonPushed, true);
            app.TrainSettings.Icon = 'settings.png';
            app.TrainSettings.Tooltip = {'Define training parameters'; ''};
            app.TrainSettings.Text = 'Training';
            app.T_SaveProgress = uicheckbox(app.TrainingProcessDesignPanel);
            app.T_SaveProgress.ValueChangedFcn = createCallbackFcn(app, @T_SaveProgressValueChanged, true);
            app.T_SaveProgress.Text = 'Save checkpoint networks';
            app.T_ExportTrainingPlots = uicheckbox(app.TrainingProcessDesignPanel);
            app.T_ExportTrainingPlots.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_ExportTrainingPlots.Text = 'Export training plots';
            app.T_RandomgeneratorseedLabel = uilabel(app.TrainingProcessDesignPanel);
            app.T_RandomgeneratorseedLabel.Text = 'Random seed';
            app.T_RandomGeneratorSeed = uieditfield(app.TrainingProcessDesignPanel, 'numeric');
            app.T_RandomGeneratorSeed.Limits = [0 Inf];
            app.T_RandomGeneratorSeed.RoundFractionalValues = 'on';
            app.T_RandomGeneratorSeed.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_RandomGeneratorSeed.Value = 2;
            app.BetaVersionPanel = uipanel(app.Train);
            app.BetaVersionPanel.Title = 'Beta version';
            app.T_DownsamplingLabel = uilabel(app.BetaVersionPanel);
            app.T_DownsamplingLabel.Text = 'Downsampling factor';
            app.T_DownsamplingFactor = uieditfield(app.BetaVersionPanel, 'numeric');
            app.T_DownsamplingFactor.Limits = [2 Inf];
            app.T_DownsamplingFactor.RoundFractionalValues = 'on';
            app.T_DownsamplingFactor.Tooltip = {'Downsampling factor for 2D/3D U-net and SegNet'};
            app.T_DownsamplingFactor.Value = 2;
            app.TransferLearning = uibutton(app.Train, 'push');
            app.TransferLearning.ButtonPushedFcn = createCallbackFcn(app, @TransferLearningButtonPushed, true);
            app.TransferLearning.Tooltip = {'Modify the loaded network for a different number of classes'};
            app.TransferLearning.Text = 'Transfer learning';
            app.Predict = uitab(app.Mode);
            app.Predict.Title = 'Predict';
            app.PreviewresultsPanel = uipanel(app.Predict);
            app.PreviewresultsPanel.Title = 'Preview results';
            app.loadImagesAndModels = uibutton(app.PreviewresultsPanel, 'push');
            app.loadImagesAndModels.ButtonPushedFcn = createCallbackFcn(app, @loadImagesAndModelsButtonPushed, true);
            app.loadImagesAndModels.Text = 'Load images and models';
            app.LoadPredictions = uibutton(app.PreviewresultsPanel, 'push');
            app.LoadPredictions.ButtonPushedFcn = createCallbackFcn(app, @LoadPredictionsButtonPushed, true);
            app.LoadPredictions.Text = 'Load prediction scores';
            app.PreviewResultsText = uilabel(app.PreviewresultsPanel);
            app.PreviewResultsText.Text = 'Use buttons below to preview predictions.';
            app.PreviewResultsText2 = uilabel(app.PreviewresultsPanel);
            app.PreviewResultsText2.Text = 'Evaluate segmentation';
            app.EvaluateSegmentation = uibutton(app.PreviewresultsPanel, 'push');
            app.EvaluateSegmentation.ButtonPushedFcn = createCallbackFcn(app, @EvaluateSegmentationButtonPushed, true);
            app.EvaluateSegmentation.Text = 'Evaluate segmentation';
            app.loadModels = uibutton(app.PreviewresultsPanel, 'push');
            app.loadModels.ButtonPushedFcn = createCallbackFcn(app, @loadImagesAndModelsButtonPushed, true);
            app.loadModels.Tooltip = {'If images are already loaded, load only the generated models'};
            app.loadModels.Text = 'Load models';
            app.SettingsPanel = uipanel(app.Predict);
            app.SettingsPanel.Title = 'Settings';
            app.P_OverlappingTiles = uicheckbox(app.SettingsPanel);
            app.P_OverlappingTiles.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_OverlappingTiles.Text = 'Overlapping tiles';
            app.P_DynamicMasking = uicheckbox(app.SettingsPanel);
            app.P_DynamicMasking.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_DynamicMasking.Text = 'Dynamic masking';
            app.P_DynamicMaskSettings = uibutton(app.SettingsPanel, 'push');
            app.P_DynamicMaskSettings.ButtonPushedFcn = createCallbackFcn(app, @P_DynamicMaskSettingsButtonPushed, true);
            app.P_DynamicMaskSettings.Icon = 'settings.png';
            app.P_DynamicMaskSettings.Tooltip = {'press to set settings for dynamic masking'};
            app.P_DynamicMaskSettings.Text = '';
            app.previewDynamicMask = uibutton(app.SettingsPanel, 'push');
            app.previewDynamicMask.ButtonPushedFcn = createCallbackFcn(app, @previewDynamicMaskButtonPushed, true);
            app.previewDynamicMask.Icon = 'eye.png';
            app.previewDynamicMask.Tooltip = {'Press to preview blocks generated using dynamic masking. The precessed image is taken from the MIB main window'};
            app.previewDynamicMask.Text = '';
            app.BatchsizeforpredictionLabel = uilabel(app.SettingsPanel);
            app.BatchsizeforpredictionLabel.Text = 'Batch size for prediction';
            app.P_MiniBatchSize = uieditfield(app.SettingsPanel, 'numeric');
            app.P_MiniBatchSize.Limits = [1 Inf];
            app.P_MiniBatchSize.RoundFractionalValues = 'on';
            app.P_MiniBatchSize.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_MiniBatchSize.Value = 1;
            app.ScorefilesDropDownLabel = uilabel(app.SettingsPanel);
            app.ScorefilesDropDownLabel.Text = 'Score files';
            app.P_ScoreFiles = uidropdown(app.SettingsPanel);
            app.P_ScoreFiles.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ModelfilesLabel = uilabel(app.SettingsPanel);
            app.ModelfilesLabel.Text = 'Model files';
            app.P_ModelFiles = uidropdown(app.SettingsPanel);
            app.P_ModelFiles.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.PredictionengineLabel = uilabel(app.SettingsPanel);
            app.PredictionengineLabel.Text = 'Prediction engine';
            app.P_PredictionMode = uidropdown(app.SettingsPanel);
            app.P_PredictionMode.Items = {'Blocked-image', 'Legacy'};
            app.P_PredictionMode.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_PredictionMode.Value = 'Blocked-image';
            app.Label = uilabel(app.SettingsPanel);
            app.Label.Text = '%%';
            app.P_OverlappingTilesPercentage = uieditfield(app.SettingsPanel, 'numeric');
            app.P_OverlappingTilesPercentage.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_OverlappingTilesPercentage.Value = 5;
            app.ExploreActivations = uibutton(app.SettingsPanel, 'push');
            app.ExploreActivations.ButtonPushedFcn = createCallbackFcn(app, @ExploreActivationsButtonPushed, true);
            app.ExploreActivations.Text = 'Explore activations';
            app.PatchwisesettingsPanel = uipanel(app.SettingsPanel);
            app.PatchwisesettingsPanel.Title = 'Patch-wise settings';
            app.P_PatchWiseUpsample = uicheckbox(app.PatchwisesettingsPanel);
            app.P_PatchWiseUpsample.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_PatchWiseUpsample.Text = ' upsample prediction';
            app.Options = uitab(app.Mode);
            app.Options.Title = 'Options';
            app.ConfigfilesPanel = uipanel(app.Options);
            app.ConfigfilesPanel.Title = 'Config files';
            app.LoadButton = uibutton(app.ConfigfilesPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ConfigfilesPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ConfigFilesLabel = uilabel(app.ConfigfilesPanel);
            app.CustomtrainingplotPanel = uipanel(app.Options);
            app.CustomtrainingplotPanel.Title = 'Custom training plot';
            app.O_CustomTrainingProgressWindow = uicheckbox(app.CustomtrainingplotPanel);
            app.O_CustomTrainingProgressWindow.ValueChangedFcn = createCallbackFcn(app, @O_CustomTrainingProgressWindowValueChanged, true);
            app.O_CustomTrainingProgressWindow.Text = 'Custom training progress window';
            app.RefreshrateiterLabel = uilabel(app.CustomtrainingplotPanel);
            app.RefreshrateiterLabel.Text = 'Refresh rate (iter)';
            app.NumberofpointsLabel = uilabel(app.CustomtrainingplotPanel);
            app.NumberofpointsLabel.Text = 'Number of points';
            app.O_PreviewImagePatches = uicheckbox(app.CustomtrainingplotPanel);
            app.O_PreviewImagePatches.ValueChangedFcn = createCallbackFcn(app, @O_PreviewImagePatchesValueChanged, true);
            app.O_PreviewImagePatches.Text = 'Preview image patches';
            app.FractionofimagesforpreviewLabel = uilabel(app.CustomtrainingplotPanel);
            app.FractionofimagesforpreviewLabel.Text = 'Fraction of images for preview';
            app.O_RefreshRateIter = uieditfield(app.CustomtrainingplotPanel, 'numeric');
            app.O_RefreshRateIter.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.O_RefreshRateIter.Value = 5;
            app.O_NumberOfPoints = uieditfield(app.CustomtrainingplotPanel, 'numeric');
            app.O_NumberOfPoints.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.O_NumberOfPoints.Value = 1000;
            app.O_FractionOfPreviewPatches = uieditfield(app.CustomtrainingplotPanel, 'numeric');
            app.O_FractionOfPreviewPatches.ValueDisplayFormat = '%11.5g';
            app.O_FractionOfPreviewPatches.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.O_FractionOfPreviewPatches.Value = 0.05;
            app.OptionsToolsPanel = uipanel(app.Options);
            app.OptionsToolsPanel.Title = 'Tools';
            app.O_exportNetworkToONNXButton = uibutton(app.OptionsToolsPanel, 'push');
            app.O_exportNetworkToONNXButton.ButtonPushedFcn = createCallbackFcn(app, @O_exportNetworkToONNXButtonPushed, true);
            app.O_exportNetworkToONNXButton.Tooltip = {'Export network to the ONNX Model Format'};
            app.O_exportNetworkToONNXButton.Text = 'Export network to ONNX ';
            app.O_countLabelsButton = uibutton(app.OptionsToolsPanel, 'push');
            app.O_countLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @O_countLabelsButtonPushed, true);
            app.O_countLabelsButton.Tooltip = {'Count labels in model files'};
            app.O_countLabelsButton.Text = 'Count labels';
            app.O_importNetworkButton = uibutton(app.OptionsToolsPanel, 'push');
            app.O_importNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @O_importNetworkButtonPushed, true);
            app.O_importNetworkButton.Tooltip = {'import external network to be used with DeepMIB'};
            app.O_importNetworkButton.Text = 'Import network';
            app.O_balanceClassesButton = uibutton(app.OptionsToolsPanel, 'push');
            app.O_balanceClassesButton.ButtonPushedFcn = createCallbackFcn(app, @O_balanceClassesButtonPushed, true);
            app.O_balanceClassesButton.Tooltip = {'Count labels in model files'};
            app.O_balanceClassesButton.Text = 'Balance classes';
            app.AugmentationsPanel = uipanel(app.Options);
            app.AugmentationsPanel.Title = 'Augmentations';
            app.reset2DAugmentations = uibutton(app.AugmentationsPanel, 'push');
            app.reset2DAugmentations.ButtonPushedFcn = createCallbackFcn(app, @resetAugmentations, true);
            app.reset2DAugmentations.Tooltip = {'reset all 2D augmentations to their default states'};
            app.reset2DAugmentations.Text = 'Reset';
            app.disable2DAugmentations = uibutton(app.AugmentationsPanel, 'push');
            app.disable2DAugmentations.ButtonPushedFcn = createCallbackFcn(app, @disableAugmentations, true);
            app.disable2DAugmentations.Tooltip = {'set all 2D augmentations to the OFF state'};
            app.disable2DAugmentations.Text = 'Disable';
            app.reset3DAugmentations = uibutton(app.AugmentationsPanel, 'push');
            app.reset3DAugmentations.ButtonPushedFcn = createCallbackFcn(app, @resetAugmentations, true);
            app.reset3DAugmentations.Tooltip = {'reset all 3D augmentations to their default states'};
            app.reset3DAugmentations.Text = 'Reset';
            app.disable3DAugmentations = uibutton(app.AugmentationsPanel, 'push');
            app.disable3DAugmentations.ButtonPushedFcn = createCallbackFcn(app, @disableAugmentations, true);
            app.disable3DAugmentations.Tooltip = {'set all 3D augmentations to the OFF state'};
            app.disable3DAugmentations.Text = 'Disable';
            app.Aug2DLabel = uilabel(app.AugmentationsPanel);
            app.Aug2DLabel.Text = '2D:';
            app.Aug3DLabel = uilabel(app.AugmentationsPanel);
            app.Aug3DLabel.Text = '3D:';
            app.NetworkPanel = uipanel(app.GridLayout);
            app.NetworkPanel.Title = 'Network';
            app.NetworkfilenameEditFieldLabel = uilabel(app.NetworkPanel);
            app.NetworkfilenameEditFieldLabel.Text = 'Network filename:';
            app.WorkflowLabel = uilabel(app.NetworkPanel);
            app.WorkflowLabel.Text = 'Workflow';
            app.GPULabel_2 = uilabel(app.NetworkPanel);
            app.GPULabel_2.Text = 'GPU:';
            app.NetworkFilename = uieditfield(app.NetworkPanel, 'text');
            app.NetworkFilename.ValueChangedFcn = createCallbackFcn(app, @SelectNetworkButtonPushed, true);
            app.NetworkFilename.Enable = 'off';
            app.SelectNetwork = uibutton(app.NetworkPanel, 'push');
            app.SelectNetwork.ButtonPushedFcn = createCallbackFcn(app, @SelectNetworkButtonPushed, true);
            app.SelectNetwork.Text = '...';
            app.gpuInfoBtn = uibutton(app.NetworkPanel, 'push');
            app.gpuInfoBtn.ButtonPushedFcn = createCallbackFcn(app, @gpuInfoBtnButtonPushed, true);
            app.gpuInfoBtn.Tooltip = {'GPU info: display information about the selected GPU device'};
            app.gpuInfoBtn.Text = '?';
            app.GPUDropDown = uidropdown(app.NetworkPanel);
            app.GPUDropDown.ValueChangedFcn = createCallbackFcn(app, @GPUDropDownValueChanged, true);
            app.GPUDropDown.Tooltip = {'select a GPU device to be used for training or prediction'};
            app.Workflow = uidropdown(app.NetworkPanel);
            app.Workflow.Items = {'2D Semantic', '3D Semantic', '2D Patch-wise', ''};
            app.Workflow.ValueChangedFcn = createCallbackFcn(app, @WorkflowValueChanged, true);
            app.Workflow.Value = '2D Semantic';
            app.ArchitectureLabel_2 = uilabel(app.NetworkPanel);
            app.ArchitectureLabel_2.Text = 'Architecture:';
            app.Architecture = uidropdown(app.NetworkPanel);
            app.Architecture.Items = {'U-net', 'SegNet', '', ''};
            app.Architecture.ValueChangedFcn = createCallbackFcn(app, @ArchitectureValueChanged, true);
            app.Architecture.Value = 'U-net';
            app.BottomButtonsPanel = uipanel(app.GridLayout);
            app.TrainButton = uibutton(app.BottomButtonsPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.TrainButton.Enable = 'off';
            app.TrainButton.Text = 'Train';
            app.Help = uibutton(app.BottomButtonsPanel, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Text = 'Help';
            app.PreprocessButton = uibutton(app.BottomButtonsPanel, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.PreprocessButton.Text = 'Preprocess';
            app.PredictButton = uibutton(app.BottomButtonsPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.PredictButton.Enable = 'off';
            app.PredictButton.Text = 'Predict';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mibDeepGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end