classdef mibImageFiltersR2019aGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                       matlab.ui.Figure
        FilterselectionPanel         matlab.ui.container.Panel
        FiltergroupLabel             matlab.ui.control.Label
        FilterGroup                  matlab.ui.control.DropDown
        FilternameLabel              matlab.ui.control.Label
        FilterName                   matlab.ui.control.DropDown
        FilterinfoLabel              matlab.ui.control.Label
        ThumbnailView2               matlab.ui.control.UIAxes
        ThumbnailView1               matlab.ui.control.UIAxes
        InfoTextarea                 matlab.ui.control.TextArea
        OptionsPanel                 matlab.ui.container.Panel
        DatasettypeLabel_2           matlab.ui.control.Label
        DatasetType                  matlab.ui.control.DropDown
        SourcelayerLabel             matlab.ui.control.Label
        SourceLayer                  matlab.ui.control.DropDown
        ColorchannelLabel            matlab.ui.control.Label
        ColorChannel                 matlab.ui.control.DropDown
        MaterialindexEditFieldLabel  matlab.ui.control.Label
        MaterialIndex                matlab.ui.control.EditField
        Mode3D                       matlab.ui.control.CheckBox
        UseParallelComputing         matlab.ui.control.CheckBox
        FilterparametersPanel        matlab.ui.container.Panel
        Help                         matlab.ui.control.StateButton
        Label_4                      matlab.ui.control.Label
        ActionToResult               matlab.ui.control.DropDown
        AutopreviewCheckBox          matlab.ui.control.CheckBox
        PreviewButton                matlab.ui.control.Button
        Filter                       matlab.ui.control.Button
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    properties (Access = private)
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
        end

        % Button pushed function: Filter
        function Filter_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % call a corresponding method of the controller class
            
            app.winController.Filter();
        end

        % Value changed function: Help
        function Help_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global mibPath;
            web(fullfile(mibPath, 'techdoc/html/user-interface/menu/image/image-filters.html'), '-browser');
        end

        % Value changed function: ActionToResult, ColorChannel, 
        % DatasetType, MaterialIndex, SourceLayer, 
        % UseParallelComputing
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Value changed function: FilterGroup
        function FilterGroupValueChanged(app, event)
            app.winController.FilterGroupValueChanged(event);
        end

        % Value changed function: FilterName
        function FilterNameValueChanged(app, event)
            app.winController.FilterNameValueChanged(event);
        end

        % Value changed function: Mode3D
        function Mode3DValueChanged(app, event)
            app.winController.Mode3DValueChanged();
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            app.winController.PreviewButtonPushed();
        end

        % Key press function: Figure
        function FigureKeyPress(app, event)
            % add a key press callback to remove the default Ctrl+Z callback 
            key = event.Key;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Image filters';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.Figure.KeyPressFcn = createCallbackFcn(app, @FigureKeyPress, true);
            app.FilterselectionPanel = uipanel(app.Figure);
            app.FilterselectionPanel.Title = 'Filter selection';
            app.FiltergroupLabel = uilabel(app.FilterselectionPanel);
            app.FiltergroupLabel.Text = 'Filter group:';
            app.FilterGroup = uidropdown(app.FilterselectionPanel);
            app.FilterGroup.Items = {'Basic Image Filtering in the Spatial Domain', 'Edge-Preserving Filtering'};
            app.FilterGroup.ValueChangedFcn = createCallbackFcn(app, @FilterGroupValueChanged, true);
            app.FilterGroup.Value = 'Edge-Preserving Filtering';
            app.FilternameLabel = uilabel(app.FilterselectionPanel);
            app.FilternameLabel.Text = 'Filter name:';
            app.FilterName = uidropdown(app.FilterselectionPanel);
            app.FilterName.Items = {'Average', 'Disk'};
            app.FilterName.ValueChangedFcn = createCallbackFcn(app, @FilterNameValueChanged, true);
            app.FilterName.Value = 'Disk';
            app.FilterinfoLabel = uilabel(app.FilterselectionPanel);
            app.FilterinfoLabel.Text = 'Filter info:';
            app.ThumbnailView2 = uiaxes(app.FilterselectionPanel);
            title(app.ThumbnailView2, '')
            xlabel(app.ThumbnailView2, '')
            ylabel(app.ThumbnailView2, '')
            app.ThumbnailView2.XLim = [0 130];
            app.ThumbnailView2.YLim = [0 130];
            app.ThumbnailView2.XAxisLocation = 'origin';
            app.ThumbnailView2.XTick = [];
            app.ThumbnailView2.XTickLabel = '';
            app.ThumbnailView2.YAxisLocation = 'origin';
            app.ThumbnailView2.YTick = [];
            app.ThumbnailView2.YTickLabel = '';
            app.ThumbnailView2.ZTick = [];
            app.ThumbnailView2.ZTickLabel = '';
            app.ThumbnailView1 = uiaxes(app.FilterselectionPanel);
            title(app.ThumbnailView1, '')
            xlabel(app.ThumbnailView1, '')
            ylabel(app.ThumbnailView1, '')
            app.ThumbnailView1.XLim = [0 130];
            app.ThumbnailView1.YLim = [0 130];
            app.ThumbnailView1.XAxisLocation = 'origin';
            app.ThumbnailView1.XTick = [];
            app.ThumbnailView1.XTickLabel = '';
            app.ThumbnailView1.YAxisLocation = 'origin';
            app.ThumbnailView1.YTick = [];
            app.ThumbnailView1.YTickLabel = '';
            app.ThumbnailView1.ZTick = [];
            app.ThumbnailView1.ZTickLabel = '';
            app.InfoTextarea = uitextarea(app.FilterselectionPanel);
            app.InfoTextarea.Editable = 'off';
            app.OptionsPanel = uipanel(app.Figure);
            app.OptionsPanel.Title = 'Options';
            app.DatasettypeLabel_2 = uilabel(app.OptionsPanel);
            app.DatasettypeLabel_2.Text = 'Dataset type:';
            app.DatasetType = uidropdown(app.OptionsPanel);
            app.DatasetType.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.DatasetType.Tooltip = {'Range of data to filter'};
            app.SourcelayerLabel = uilabel(app.OptionsPanel);
            app.SourcelayerLabel.Text = 'Source layer:';
            app.SourceLayer = uidropdown(app.OptionsPanel);
            app.SourceLayer.Items = {'image', 'selection', 'mask'};
            app.SourceLayer.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.SourceLayer.Tooltip = {'Target layer'};
            app.SourceLayer.Value = 'image';
            app.ColorchannelLabel = uilabel(app.OptionsPanel);
            app.ColorchannelLabel.Text = 'Color channel:';
            app.ColorChannel = uidropdown(app.OptionsPanel);
            app.ColorChannel.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ColorChannel.Tooltip = {'Color channels for filtering'};
            app.MaterialindexEditFieldLabel = uilabel(app.OptionsPanel);
            app.MaterialindexEditFieldLabel.Text = 'Material index:';
            app.MaterialIndex = uieditfield(app.OptionsPanel, 'text');
            app.MaterialIndex.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.MaterialIndex.Tooltip = {'[Models only] Index of material for filtering'};
            app.MaterialIndex.Value = '1';
            app.Mode3D = uicheckbox(app.OptionsPanel);
            app.Mode3D.ValueChangedFcn = createCallbackFcn(app, @Mode3DValueChanged, true);
            app.Mode3D.Tooltip = {'Apply 3D filter, if possible'};
            app.Mode3D.Text = '3D';
            app.UseParallelComputing = uicheckbox(app.OptionsPanel);
            app.UseParallelComputing.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.UseParallelComputing.Text = 'use parallel';
            app.FilterparametersPanel = uipanel(app.Figure);
            app.FilterparametersPanel.Title = 'Filter parameters';
            app.Help = uibutton(app.Figure, 'state');
            app.Help.ValueChangedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.Help.Text = 'Help';
            app.Label_4 = uilabel(app.Figure);
            app.Label_4.Text = '';
            app.ActionToResult = uidropdown(app.Figure);
            app.ActionToResult.Items = {'Filter image', 'Filter and subtract', 'Filter and add'};
            app.ActionToResult.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ActionToResult.Value = 'Filter image';
            app.AutopreviewCheckBox = uicheckbox(app.Figure);
            app.AutopreviewCheckBox.Text = 'Auto preview';
            app.PreviewButton = uibutton(app.Figure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.Filter = uibutton(app.Figure, 'push');
            app.Filter.ButtonPushedFcn = createCallbackFcn(app, @Filter_Callback, true);
            app.Filter.Text = 'Filter';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mibImageFiltersR2019aGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end