classdef mibWhiteBalanceGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                          matlab.ui.Figure
        CorrectAll                      matlab.ui.control.Button
        Close                           matlab.ui.control.Button
        AboutLabel                      matlab.ui.control.Label
        AboutTextArea                   matlab.ui.control.TextArea
        PickedRegion                    matlab.ui.container.ButtonGroup
        ManualValue                     matlab.ui.control.RadioButton
        MaskedAreas                     matlab.ui.control.RadioButton
        SelectedAreas                   matlab.ui.control.RadioButton
        SettingsPanel                   matlab.ui.container.Panel
        ManualwhitevalueLabel           matlab.ui.control.Label
        ManualWhiteColor                matlab.ui.control.EditField
        ColorspaceLabel                 matlab.ui.control.Label
        ColorSpace                      matlab.ui.control.DropDown
        ChromaticadaptationmethodLabel  matlab.ui.control.Label
        ChromaticAdaptationMethod       matlab.ui.control.DropDown
        CorrectCurrent                  matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = 'off';
        end

        % Button pushed function: CorrectAll, CorrectCurrent
        function CorrectBtn_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % call a corresponding method of the controller class
            app.winController.correctWhiteBalance(event.Source.Text);

        end

        % Button pushed function: Close
        function Close_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.winController.closeWindow();
        end

        % Button pushed function: HelpButton
        function Help_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.winController.Help_Callback();
            
        end

        % Value changed function: ChromaticAdaptationMethod, 
        % ColorSpace
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Selection changed function: PickedRegion
        function PickedRegionSelectionChanged(app, event)
            %selectedButton = app.PickedRegion.SelectedObject;
            app.winController.updateBatchOptFromGUI(event);
            
            if strcmp(event.Source.SelectedObject.Tag, 'ManualValue')
                app.ManualWhiteColor.Enable = 'on';
            else
                app.ManualWhiteColor.Enable = 'off';
            end
        end

        % Value changed function: ManualWhiteColor
        function ManualWhiteColorValueChanged(app, event)
            value = app.ManualWhiteColor.Value;
            val = str2num(value); %#ok<ST2NM> 
            if numel(val) ~= 3
               uialert(app.Figure, ...
                   sprintf('!!! Error !!!\n\nPlease provide 3 values that describe intensity in an area that should be white or gray!'), ...
                   'Wrong value!');
               app.ManualWhiteColor.Value = event.PreviousValue;
               return;
            end
            app.winController.updateBatchOptFromGUI(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'White balance correction';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.CorrectAll = uibutton(app.Figure, 'push');
            app.CorrectAll.ButtonPushedFcn = createCallbackFcn(app, @CorrectBtn_Callback, true);
            app.CorrectAll.Tooltip = {'correct white balance for all images of the dataset'};
            app.CorrectAll.Text = 'Correct all';
            app.Close = uibutton(app.Figure, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @Close_Callback, true);
            app.Close.Text = 'Close';
            app.AboutLabel = uilabel(app.Figure);
            app.AboutLabel.Text = 'About:';
            app.AboutTextArea = uitextarea(app.Figure);
            app.AboutTextArea.Editable = 'off';
            app.AboutTextArea.Value = {'Adjust color balance of RGB image with chromatic adaptation. Use the mask or selection layer to pick regions in the image that should look white or gray. '; 'Do not pick areas with saturated signal.'};
            app.PickedRegion = uibuttongroup(app.Figure);
            app.PickedRegion.SelectionChangedFcn = createCallbackFcn(app, @PickedRegionSelectionChanged, true);
            app.PickedRegion.Title = 'Picked region';
            app.ManualValue = uiradiobutton(app.PickedRegion);
            app.ManualValue.Text = ' Manual value';
            app.MaskedAreas = uiradiobutton(app.PickedRegion);
            app.MaskedAreas.Text = ' Masked areas';
            app.SelectedAreas = uiradiobutton(app.PickedRegion);
            app.SelectedAreas.Text = ' Selected areas';
            app.SelectedAreas.Value = true;
            app.SettingsPanel = uipanel(app.Figure);
            app.SettingsPanel.Title = 'Settings';
            app.ManualwhitevalueLabel = uilabel(app.SettingsPanel);
            app.ManualwhitevalueLabel.Text = 'Manual white value:';
            app.ManualWhiteColor = uieditfield(app.SettingsPanel, 'text');
            app.ManualWhiteColor.ValueChangedFcn = createCallbackFcn(app, @ManualWhiteColorValueChanged, true);
            app.ManualWhiteColor.Enable = 'off';
            app.ManualWhiteColor.Value = '128 128 128';
            app.ColorspaceLabel = uilabel(app.SettingsPanel);
            app.ColorspaceLabel.Text = 'Color space:';
            app.ColorSpace = uidropdown(app.SettingsPanel);
            app.ColorSpace.Items = {'sRGB', 'Adobe-RGB-1998', 'linear-RGB'};
            app.ColorSpace.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ColorSpace.Tooltip = {'highlight removed areas as mask'};
            app.ColorSpace.Value = 'sRGB';
            app.ChromaticadaptationmethodLabel = uilabel(app.SettingsPanel);
            app.ChromaticadaptationmethodLabel.Text = 'Chromatic adaptation method:';
            app.ChromaticAdaptationMethod = uidropdown(app.SettingsPanel);
            app.ChromaticAdaptationMethod.Items = {'bradford'};
            app.ChromaticAdaptationMethod.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ChromaticAdaptationMethod.Tooltip = {'highlight removed areas as mask'};
            app.ChromaticAdaptationMethod.Value = 'bradford';
            app.CorrectCurrent = uibutton(app.Figure, 'push');
            app.CorrectCurrent.ButtonPushedFcn = createCallbackFcn(app, @CorrectBtn_Callback, true);
            app.CorrectCurrent.Tooltip = {'correct white balance for the currently shown image'};
            app.CorrectCurrent.Text = 'Correct current';
            app.HelpButton = uibutton(app.Figure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mibWhiteBalanceGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end