classdef mibWoundHealingAssayGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                          matlab.ui.Figure
        AboutTextArea                   matlab.ui.control.TextArea
        AboutTextAreaLabel              matlab.ui.control.Label
        WoundhealingsettingsPanel       matlab.ui.container.Panel
        ShowInteractivePlot             matlab.ui.control.CheckBox
        DownsampleImages                matlab.ui.control.NumericEditField
        DownsampleresultingimagesLabel  matlab.ui.control.Label
        TimeStep                        matlab.ui.control.NumericEditField
        TimestepLabel                   matlab.ui.control.Label
        PixelSize                       matlab.ui.control.NumericEditField
        PixelsizeLabel                  matlab.ui.control.Label
        StitchingsettingsPanel          matlab.ui.container.Panel
        ConvertToGrayscale              matlab.ui.control.CheckBox
        NoColumns                       matlab.ui.control.NumericEditField
        NoColumnsEditFieldLabel         matlab.ui.control.Label
        NoRows                          matlab.ui.control.NumericEditField
        NoRowsEditFieldLabel            matlab.ui.control.Label
        WoundHealing                    matlab.ui.control.Button
        OutputDir                       matlab.ui.control.EditField
        OutputButton                    matlab.ui.control.Button
        SelectDirectories               matlab.ui.control.Button
        SelectedDirectories             matlab.ui.control.TextArea
        SelecteddirectoriesLabel        matlab.ui.control.Label
        Close                           matlab.ui.control.Button
        Calculate                       matlab.ui.control.Button
        Extension                       matlab.ui.control.EditField
        ExtensionLabel                  matlab.ui.control.Label
        Help                            matlab.ui.control.StateButton
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            
        end

        % Button pushed function: Calculate
        function Calculate_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % call a corresponding method of the controller class
            
            app.winController.Stitch();
        end

        % Button pushed function: Close
        function Close_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.winController.closeWindow();
        end

        % Value changed function: Help
        function Help_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global mibPath;
            web(fullfile(mibPath, 'Plugins', 'MyPlugins', 'mibPlugin', 'Help', 'index.html'), '-helpbrowser');
        end

        % Value changed function: ConvertToGrayscale, DownsampleImages, 
        % Extension, NoColumns, NoRows, PixelSize, ShowInteractivePlot, 
        % TimeStep
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Button pushed function: SelectDirectories
        function SelectDirectoriesButtonPushed(app, event)
            app.winController.SelectDirectories();
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            app.winController.SelectOutputDirectory();
        end

        % Button pushed function: WoundHealing
        function WoundHealingButtonPushed(app, event)
            app.winController.WoundHealing();
        end

        % Value changed function: OutputDir
        function OutputDirValueChanged(app, event)
            value = app.OutputDir.Value;
            app.winController.updateOutputPath(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Grid zero-overlap stitching and wound healing';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.Help = uibutton(app.Figure, 'state');
            app.Help.ValueChangedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.Help.Text = 'Help';
            app.ExtensionLabel = uilabel(app.Figure);
            app.ExtensionLabel.Text = 'Extension:';
            app.Extension = uieditfield(app.Figure, 'text');
            app.Extension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.Extension.Value = 'tif';
            app.Calculate = uibutton(app.Figure, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @Calculate_Callback, true);
            app.Calculate.Tooltip = {'Stitch images in the selected directories'};
            app.Calculate.Text = 'Stitch';
            app.Close = uibutton(app.Figure, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @Close_Callback, true);
            app.Close.Text = 'Close';
            app.SelecteddirectoriesLabel = uilabel(app.Figure);
            app.SelecteddirectoriesLabel.Text = 'Selected directories';
            app.SelectedDirectories = uitextarea(app.Figure);
            app.SelectedDirectories.Editable = 'off';
            app.SelectedDirectories.Value = {'1. Stitching'; '- define the filename extension'; '- define number of images in the grid (left-up to down-right)'; '- select output directory and press the Stitch button'; ''; '2. Wound healing'; '- define directories with the assays and the output directory for results'; '- define pixel size and time step'; '- press the Wound healing button to start analysis'};
            app.SelectDirectories = uibutton(app.Figure, 'push');
            app.SelectDirectories.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoriesButtonPushed, true);
            app.SelectDirectories.Text = 'Select directories...';
            app.OutputButton = uibutton(app.Figure, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output...';
            app.OutputDir = uieditfield(app.Figure, 'text');
            app.OutputDir.ValueChangedFcn = createCallbackFcn(app, @OutputDirValueChanged, true);
            app.WoundHealing = uibutton(app.Figure, 'push');
            app.WoundHealing.ButtonPushedFcn = createCallbackFcn(app, @WoundHealingButtonPushed, true);
            app.WoundHealing.Tooltip = {'press to start wound healing assay for the selected directories'};
            app.WoundHealing.Text = 'Wound healing';
            app.StitchingsettingsPanel = uipanel(app.Figure);
            app.StitchingsettingsPanel.Title = 'Stitching settings';
            app.NoRowsEditFieldLabel = uilabel(app.StitchingsettingsPanel);
            app.NoRowsEditFieldLabel.Text = 'No. Rows';
            app.NoRows = uieditfield(app.StitchingsettingsPanel, 'numeric');
            app.NoRows.Limits = [1 Inf];
            app.NoRows.RoundFractionalValues = 'on';
            app.NoRows.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.NoRows.Value = 3;
            app.NoColumnsEditFieldLabel = uilabel(app.StitchingsettingsPanel);
            app.NoColumnsEditFieldLabel.Text = 'No. Columns';
            app.NoColumns = uieditfield(app.StitchingsettingsPanel, 'numeric');
            app.NoColumns.Limits = [1 Inf];
            app.NoColumns.RoundFractionalValues = 'on';
            app.NoColumns.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.NoColumns.Value = 3;
            app.ConvertToGrayscale = uicheckbox(app.StitchingsettingsPanel);
            app.ConvertToGrayscale.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ConvertToGrayscale.Text = 'convert to grayscale';
            app.ConvertToGrayscale.Value = true;
            app.WoundhealingsettingsPanel = uipanel(app.Figure);
            app.WoundhealingsettingsPanel.Title = 'Wound healing settings';
            app.PixelsizeLabel = uilabel(app.WoundhealingsettingsPanel);
            app.PixelsizeLabel.Text = 'Pixel size';
            app.PixelSize = uieditfield(app.WoundhealingsettingsPanel, 'numeric');
            app.PixelSize.Limits = [0 Inf];
            app.PixelSize.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.PixelSize.Value = 1;
            app.TimestepLabel = uilabel(app.WoundhealingsettingsPanel);
            app.TimestepLabel.Text = 'Time step';
            app.TimeStep = uieditfield(app.WoundhealingsettingsPanel, 'numeric');
            app.TimeStep.Limits = [0 Inf];
            app.TimeStep.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.TimeStep.Value = 1;
            app.DownsampleresultingimagesLabel = uilabel(app.WoundhealingsettingsPanel);
            app.DownsampleresultingimagesLabel.Text = 'Downsample resulting images';
            app.DownsampleImages = uieditfield(app.WoundhealingsettingsPanel, 'numeric');
            app.DownsampleImages.Limits = [0 100];
            app.DownsampleImages.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.DownsampleImages.Tooltip = {'Percentage to downsample resulting images'};
            app.DownsampleImages.Value = 50;
            app.ShowInteractivePlot = uicheckbox(app.WoundhealingsettingsPanel);
            app.ShowInteractivePlot.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ShowInteractivePlot.Text = 'show interactive plot';
            app.ShowInteractivePlot.Value = true;
            app.AboutTextAreaLabel = uilabel(app.Figure);
            app.AboutTextAreaLabel.Text = 'About';
            app.AboutTextArea = uitextarea(app.Figure);
            app.AboutTextArea.Editable = 'off';
            app.AboutTextArea.Value = {'Use this plugin to '; '1) stich grid collection of images that have 0 pixel overlap; '; '2) run a wound healing assay over the stitched images'};
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mibWoundHealingAssayGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end