classdef ImageConverterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                       matlab.ui.Figure
        Convert                      matlab.ui.control.Button
        Close                        matlab.ui.control.Button
        InfoPanel                    matlab.ui.container.Panel
        infoText                     matlab.ui.control.HTML
        InputPanel                   matlab.ui.container.Panel
        InputdirectoryLabel          matlab.ui.control.Label
        InputDirectory               matlab.ui.control.EditField
        SelectInputDirectory         matlab.ui.control.Button
        IncludeSubfolders            matlab.ui.control.CheckBox
        ImagefilenameextensionLabel  matlab.ui.control.Label
        InputImageFormatExtension    matlab.ui.control.DropDown
        BioFormatsReader             matlab.ui.control.CheckBox
        BioFormatsInputImageFormatExtensionLabel  matlab.ui.control.Label
        BioFormatsInputImageFormatExtension  matlab.ui.control.DropDown
        IndexEditFieldLabel          matlab.ui.control.Label
        BioFormatsIndex              matlab.ui.control.NumericEditField
        OutputPanel                  matlab.ui.container.Panel
        OutputdirectoryLabel         matlab.ui.control.Label
        OutputDirectory              matlab.ui.control.EditField
        SelectOutputDirectory        matlab.ui.control.Button
        OutputformatLabel            matlab.ui.control.Label
        OutputImageFormatExtension   matlab.ui.control.DropDown
        FilenameprefixLabel          matlab.ui.control.Label
        Prefix                       matlab.ui.control.EditField
        SuffixLabel                  matlab.ui.control.Label
        Suffix                       matlab.ui.control.EditField
        ParallelProcessing           matlab.ui.control.CheckBox
        PyramidalTIFgenerate         matlab.ui.control.CheckBox
        TIFcompressionDropDownLabel  matlab.ui.control.Label
        PyramidalTIFcompression      matlab.ui.control.DropDown
        LevelsEditFieldLabel         matlab.ui.control.Label
        PyramidalTIFlevels           matlab.ui.control.EditField
        DiscardColormap              matlab.ui.control.CheckBox
        HelpButton                   matlab.ui.control.Button
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            
        end

        % Button pushed function: Convert
        function Convert_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % call a corresponding method of the controller class
            
            app.winController.Convert();
        end

        % Button pushed function: Close
        function Close_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.winController.closeWindow();
        end

        % Value changed function: BioFormatsIndex, 
        % BioFormatsInputImageFormatExtension, DiscardColormap, 
        % IncludeSubfolders, InputDirectory, 
        % InputImageFormatExtension, OutputDirectory, 
        % OutputImageFormatExtension, ParallelProcessing, Prefix, 
        % PyramidalTIFcompression, PyramidalTIFlevels, Suffix
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Button pushed function: SelectInputDirectory, 
        % SelectOutputDirectory
        function SelectInputDirectoryButtonPushed(app, event)
            app.winController.selectDirectory(event);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web('https://youtu.be/Xu1waZ913bE', '-helpbrowser');
        end

        % Value changed function: PyramidalTIFgenerate
        function PyramidalTIFgenerateValueChanged(app, event)
            app.winController.updateBatchOptFromGUI(event);
            
            if app.PyramidalTIFgenerate.Value
                app.OutputImageFormatExtension.Value = 'tif';
                event2.Source = app.OutputImageFormatExtension;
                app.winController.updateBatchOptFromGUI(event2);
                app.PyramidalTIFlevels.Enable = 'on';
                app.PyramidalTIFcompression.Enable = 'on';
            else
                app.PyramidalTIFlevels.Enable = 'off';
                app.PyramidalTIFcompression.Enable = 'off';
            end
        end

        % Value changed function: BioFormatsReader
        function BioFormatsReaderValueChanged(app, event)
            app.winController.BioFormatsReader_ValueChanged(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'File converter';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.Convert = uibutton(app.Figure, 'push');
            app.Convert.ButtonPushedFcn = createCallbackFcn(app, @Convert_Callback, true);
            app.Convert.Text = 'Convert';
            app.Close = uibutton(app.Figure, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @Close_Callback, true);
            app.Close.Text = 'Close';
            app.InfoPanel = uipanel(app.Figure);
            app.InfoPanel.Title = 'Info';
            app.infoText = uihtml(app.InfoPanel);
            app.InputPanel = uipanel(app.Figure);
            app.InputPanel.Title = 'Input';
            app.InputdirectoryLabel = uilabel(app.InputPanel);
            app.InputdirectoryLabel.Text = 'Input directory:';
            app.InputDirectory = uieditfield(app.InputPanel, 'text');
            app.InputDirectory.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.InputDirectory.Value = 'test';
            app.SelectInputDirectory = uibutton(app.InputPanel, 'push');
            app.SelectInputDirectory.ButtonPushedFcn = createCallbackFcn(app, @SelectInputDirectoryButtonPushed, true);
            app.SelectInputDirectory.Text = '...';
            app.IncludeSubfolders = uicheckbox(app.InputPanel);
            app.IncludeSubfolders.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.IncludeSubfolders.Text = 'Include subfolders';
            app.ImagefilenameextensionLabel = uilabel(app.InputPanel);
            app.ImagefilenameextensionLabel.Text = 'Image filename extension';
            app.InputImageFormatExtension = uidropdown(app.InputPanel);
            app.InputImageFormatExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.InputImageFormatExtension.Value = 'Option 4';
            app.BioFormatsReader = uicheckbox(app.InputPanel);
            app.BioFormatsReader.ValueChangedFcn = createCallbackFcn(app, @BioFormatsReaderValueChanged, true);
            app.BioFormatsReader.Text = 'Bio-Formats reader';
            app.BioFormatsInputImageFormatExtensionLabel = uilabel(app.InputPanel);
            app.BioFormatsInputImageFormatExtensionLabel.Text = 'Image filename extension';
            app.BioFormatsInputImageFormatExtension = uidropdown(app.InputPanel);
            app.BioFormatsInputImageFormatExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.BioFormatsInputImageFormatExtension.Enable = 'off';
            app.BioFormatsInputImageFormatExtension.Value = 'Option 4';
            app.IndexEditFieldLabel = uilabel(app.InputPanel);
            app.IndexEditFieldLabel.Text = 'Index';
            app.BioFormatsIndex = uieditfield(app.InputPanel, 'numeric');
            app.BioFormatsIndex.Limits = [0 Inf];
            app.BioFormatsIndex.RoundFractionalValues = 'on';
            app.BioFormatsIndex.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.BioFormatsIndex.Enable = 'off';
            app.BioFormatsIndex.Value = 1;
            app.OutputPanel = uipanel(app.Figure);
            app.OutputPanel.Title = 'Output';
            app.OutputdirectoryLabel = uilabel(app.OutputPanel);
            app.OutputdirectoryLabel.Text = 'Output directory:';
            app.OutputDirectory = uieditfield(app.OutputPanel, 'text');
            app.OutputDirectory.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.OutputDirectory.Value = 'test';
            app.SelectOutputDirectory = uibutton(app.OutputPanel, 'push');
            app.SelectOutputDirectory.ButtonPushedFcn = createCallbackFcn(app, @SelectInputDirectoryButtonPushed, true);
            app.SelectOutputDirectory.Text = '...';
            app.OutputformatLabel = uilabel(app.OutputPanel);
            app.OutputformatLabel.Text = 'Output format:';
            app.OutputImageFormatExtension = uidropdown(app.OutputPanel);
            app.OutputImageFormatExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.OutputImageFormatExtension.Value = 'Option 4';
            app.FilenameprefixLabel = uilabel(app.OutputPanel);
            app.FilenameprefixLabel.Text = 'Filename prefix';
            app.Prefix = uieditfield(app.OutputPanel, 'text');
            app.Prefix.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.SuffixLabel = uilabel(app.OutputPanel);
            app.SuffixLabel.Text = 'Suffix';
            app.Suffix = uieditfield(app.OutputPanel, 'text');
            app.Suffix.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ParallelProcessing = uicheckbox(app.OutputPanel);
            app.ParallelProcessing.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ParallelProcessing.Text = 'Parallel processing';
            app.PyramidalTIFgenerate = uicheckbox(app.OutputPanel);
            app.PyramidalTIFgenerate.ValueChangedFcn = createCallbackFcn(app, @PyramidalTIFgenerateValueChanged, true);
            app.PyramidalTIFgenerate.Text = 'Generate pyramidal TIFs';
            app.TIFcompressionDropDownLabel = uilabel(app.OutputPanel);
            app.TIFcompressionDropDownLabel.Text = 'TIF compression';
            app.PyramidalTIFcompression = uidropdown(app.OutputPanel);
            app.PyramidalTIFcompression.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.PyramidalTIFcompression.Enable = 'off';
            app.LevelsEditFieldLabel = uilabel(app.OutputPanel);
            app.LevelsEditFieldLabel.Text = 'Levels';
            app.PyramidalTIFlevels = uieditfield(app.OutputPanel, 'text');
            app.PyramidalTIFlevels.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.PyramidalTIFlevels.Enable = 'off';
            app.PyramidalTIFlevels.Value = '1, 2, 3, 4';
            app.DiscardColormap = uicheckbox(app.OutputPanel);
            app.DiscardColormap.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.DiscardColormap.Text = 'discard colormap';
            app.HelpButton = uibutton(app.Figure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageConverterGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end