classdef MultiRenameToolGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                     matlab.ui.Figure
        MainGridLayout             matlab.ui.container.GridLayout
        autoPreview                matlab.ui.control.CheckBox
        PreviewButton              matlab.ui.control.Button
        ReplacePanel               matlab.ui.container.Panel
        ExcludePrefixes            matlab.ui.control.EditField
        ExcludeprefixesLabel       matlab.ui.control.Label
        AddZerosToNumbers          matlab.ui.control.CheckBox
        Lettercase                 matlab.ui.control.DropDown
        LettercaseDropDownLabel    matlab.ui.control.Label
        ReplaceWith                matlab.ui.control.EditField
        ReplaceEditFieldLabel      matlab.ui.control.Label
        SearchFor                  matlab.ui.control.EditField
        SearchEditFieldLabel       matlab.ui.control.Label
        MatchCase                  matlab.ui.control.CheckBox
        CounterSettingsPanel       matlab.ui.container.Panel
        CounterStep                matlab.ui.control.Spinner
        StepSpinnerLabel           matlab.ui.control.Label
        CounterStart               matlab.ui.control.Spinner
        StartSpinnerLabel          matlab.ui.control.Label
        CounterDigits              matlab.ui.control.Spinner
        DigitsSpinnerLabel         matlab.ui.control.Label
        ExtensionTemplatePanel     matlab.ui.container.Panel
        ExtensionTemplate          matlab.ui.control.EditField
        FilenameTemplatePanel      matlab.ui.container.Panel
        infoHTML1                  matlab.ui.control.HTML
        FilenameTemplate           matlab.ui.control.EditField
        Help                       matlab.ui.control.StateButton
        Rename                     matlab.ui.control.Button
        Close                      matlab.ui.control.Button
        FileListTable              matlab.ui.control.Table
        InputPanel                 matlab.ui.container.Panel
        PathToFiles                matlab.ui.control.EditField
        PathtofilesEditFieldLabel  matlab.ui.control.Label
        SelectFilesButton          matlab.ui.control.Button
        FileListTableContextMenu   matlab.ui.container.ContextMenu
        RemoveFromList             matlab.ui.container.Menu
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            
        end

        % Button pushed function: Rename
        function Rename_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % call a corresponding method of the controller class
            app.winController.rename();
        end

        % Button pushed function: Close
        function Close_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.winController.closeWindow();
        end

        % Value changed function: Help
        function Help_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.winController.helpButton_Callback();
        end

        % Value changed function: CounterDigits, CounterStart, 
        % CounterStep, ExcludePrefixes, ExtensionTemplate, 
        % FilenameTemplate, Lettercase, MatchCase, ReplaceWith, SearchFor
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            app.winController.selectFiles();
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            app.winController.preview();
        end

        % Menu selected function: RemoveFromList
        function RemoveFromListMenuSelected(app, event)
            app.winController.FileListTableContextMenu(event.Source.Tag);
        end

        % Value changed function: AddZerosToNumbers
        function AddZerosToNumbersValueChanged(app, event)
            if app.AddZerosToNumbers.Value
                app.ExcludePrefixes.Enable = 'on';
            else
                app.ExcludePrefixes.Enable = 'off';
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Value changed function: autoPreview
        function autoPreviewValueChanged(app, event)
            app.winController.autoPreviewValueChanged();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Multi-rename tool';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.MainGridLayout = uigridlayout(app.Figure);
            app.InputPanel = uipanel(app.MainGridLayout);
            app.SelectFilesButton = uibutton(app.InputPanel, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select files...';
            app.PathtofilesEditFieldLabel = uilabel(app.InputPanel);
            app.PathtofilesEditFieldLabel.Text = 'Path to files';
            app.PathToFiles = uieditfield(app.InputPanel, 'text');
            app.PathToFiles.Editable = 'off';
            app.FileListTable = uitable(app.MainGridLayout);
            app.FileListTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.FileListTable.RowName = {};
            app.Close = uibutton(app.MainGridLayout, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @Close_Callback, true);
            app.Close.Text = 'Close';
            app.Rename = uibutton(app.MainGridLayout, 'push');
            app.Rename.ButtonPushedFcn = createCallbackFcn(app, @Rename_Callback, true);
            app.Rename.Text = 'Rename';
            app.Help = uibutton(app.MainGridLayout, 'state');
            app.Help.ValueChangedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.Help.Text = 'Help';
            app.FilenameTemplatePanel = uipanel(app.MainGridLayout);
            app.FilenameTemplatePanel.Title = 'Filename template';
            app.FilenameTemplate = uieditfield(app.FilenameTemplatePanel, 'text');
            app.FilenameTemplate.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.FilenameTemplate.Value = '[N]';
            app.infoHTML1 = uihtml(app.FilenameTemplatePanel);
            app.ExtensionTemplatePanel = uipanel(app.MainGridLayout);
            app.ExtensionTemplatePanel.Title = 'Extension template';
            app.ExtensionTemplate = uieditfield(app.ExtensionTemplatePanel, 'text');
            app.ExtensionTemplate.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ExtensionTemplate.Value = '[E]';
            app.CounterSettingsPanel = uipanel(app.MainGridLayout);
            app.CounterSettingsPanel.Title = 'Counter settings';
            app.DigitsSpinnerLabel = uilabel(app.CounterSettingsPanel);
            app.DigitsSpinnerLabel.Text = 'Digits:';
            app.CounterDigits = uispinner(app.CounterSettingsPanel);
            app.CounterDigits.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.CounterDigits.Value = 5;
            app.StartSpinnerLabel = uilabel(app.CounterSettingsPanel);
            app.StartSpinnerLabel.Text = 'Start';
            app.CounterStart = uispinner(app.CounterSettingsPanel);
            app.CounterStart.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.CounterStart.Value = 5;
            app.StepSpinnerLabel = uilabel(app.CounterSettingsPanel);
            app.StepSpinnerLabel.Text = 'Step';
            app.CounterStep = uispinner(app.CounterSettingsPanel);
            app.CounterStep.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.CounterStep.Value = 2;
            app.ReplacePanel = uipanel(app.MainGridLayout);
            app.ReplacePanel.Title = 'Search and replace';
            app.MatchCase = uicheckbox(app.ReplacePanel);
            app.MatchCase.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.MatchCase.Text = 'Match case';
            app.SearchEditFieldLabel = uilabel(app.ReplacePanel);
            app.SearchEditFieldLabel.Text = 'Search';
            app.SearchFor = uieditfield(app.ReplacePanel, 'text');
            app.SearchFor.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ReplaceEditFieldLabel = uilabel(app.ReplacePanel);
            app.ReplaceEditFieldLabel.Text = 'Replace';
            app.ReplaceWith = uieditfield(app.ReplacePanel, 'text');
            app.ReplaceWith.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.LettercaseDropDownLabel = uilabel(app.ReplacePanel);
            app.LettercaseDropDownLabel.Text = 'Letter case';
            app.Lettercase = uidropdown(app.ReplacePanel);
            app.Lettercase.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.AddZerosToNumbers = uicheckbox(app.ReplacePanel);
            app.AddZerosToNumbers.ValueChangedFcn = createCallbackFcn(app, @AddZerosToNumbersValueChanged, true);
            app.AddZerosToNumbers.Text = 'fix numbers by adding leading zeros';
            app.ExcludeprefixesLabel = uilabel(app.ReplacePanel);
            app.ExcludeprefixesLabel.Text = 'Exclude prefixes';
            app.ExcludePrefixes = uieditfield(app.ReplacePanel, 'text');
            app.ExcludePrefixes.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ExcludePrefixes.Enable = 'off';
            app.PreviewButton = uibutton(app.MainGridLayout, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview result';
            app.autoPreview = uicheckbox(app.MainGridLayout);
            app.autoPreview.ValueChangedFcn = createCallbackFcn(app, @autoPreviewValueChanged, true);
            app.autoPreview.Text = 'auto preview';
            app.FileListTableContextMenu = uicontextmenu(app.Figure);
            app.RemoveFromList = uimenu(app.FileListTableContextMenu);
            app.RemoveFromList.MenuSelectedFcn = createCallbackFcn(app, @RemoveFromListMenuSelected, true);
            app.RemoveFromList.Text = 'Remove from the list';
            % Assign app.FileListTableContextMenu
            app.FileListTable.ContextMenu = app.FileListTableContextMenu;
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultiRenameToolGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end