classdef GolgiOrientationAnalysisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                        matlab.ui.Figure
        mainGridLayout                matlab.ui.container.GridLayout
        buttomGridLayout              matlab.ui.container.GridLayout
        Close                         matlab.ui.control.Button
        Calculate                     matlab.ui.control.Button
        Help                          matlab.ui.control.StateButton
        outputFileGridLayout          matlab.ui.container.GridLayout
        OutputFilename                matlab.ui.control.EditField
        selectOutputFilename          matlab.ui.control.Button
        outputSubfolderLabel          matlab.ui.control.Label
        filenameExtensionGridLayout   matlab.ui.container.GridLayout
        FilenameModelExtension        matlab.ui.control.DropDown
        ModelsDropDownLabel           matlab.ui.control.Label
        FilenameImageExtension        matlab.ui.control.DropDown
        ImagesDropDownLabel           matlab.ui.control.Label
        FilenameextensionsLabel       matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        CompleteModelFilesTab         matlab.ui.container.Tab
        CompleteModelFilesGridLayout  matlab.ui.container.GridLayout
        celloutlineModelPath          matlab.ui.control.EditField
        selectCelloutlineModel        matlab.ui.control.Button
        celloutlinesModelLabel        matlab.ui.control.Label
        nucleiModelPath               matlab.ui.control.EditField
        selectNucleiModel             matlab.ui.control.Button
        nucleiModelLabel              matlab.ui.control.Label
        golgiModelPath                matlab.ui.control.EditField
        selectGolgiModel              matlab.ui.control.Button
        golgiModelLabel               matlab.ui.control.Label
        imagePath                     matlab.ui.control.EditField
        selectImagePath               matlab.ui.control.Button
        ImageFilenameLabel            matlab.ui.control.Label
        CroppedCellsDirsTab           matlab.ui.container.Tab
        CroppedCellsDirsGridLayout    matlab.ui.container.GridLayout
        InputDirectories              matlab.ui.control.ListBox
        directoriesLeftGridLayout     matlab.ui.container.GridLayout
        RemoveAllInputDir             matlab.ui.control.Button
        RemoveSelectedInputDir        matlab.ui.control.Button
        selectInputDirsButton         matlab.ui.control.Button
        inputDirectoriesLabel         matlab.ui.control.Label
        SettingsTab                   matlab.ui.container.Tab
        settingsGridLayout            matlab.ui.container.GridLayout
        midSettignsGridLayout         matlab.ui.container.GridLayout
        DistancefromnucleusPanel      matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        DistanceMaxFromNucleus        matlab.ui.control.NumericEditField
        SizethresholdPanel            matlab.ui.container.Panel
        thresholdGridLayout           matlab.ui.container.GridLayout
        ThresholdCell                 matlab.ui.control.NumericEditField
        ThresholdNucleus              matlab.ui.control.NumericEditField
        ThresholdGolgi                matlab.ui.control.NumericEditField
        MaterialsPanel                matlab.ui.container.Panel
        materialsGridLayout           matlab.ui.container.GridLayout
        MaterialCell                  matlab.ui.control.NumericEditField
        CellshapeEditFieldLabel       matlab.ui.control.Label
        MaterialNucleus               matlab.ui.control.NumericEditField
        NucleusEditFieldLabel         matlab.ui.control.Label
        MaterialGolgi                 matlab.ui.control.NumericEditField
        GolgiEditFieldLabel           matlab.ui.control.Label
        topSettingsGridLayout         matlab.ui.container.GridLayout
        Method                        matlab.ui.control.DropDown
        MethodDropDownLabel           matlab.ui.control.Label
        ModeAndInformationPanel       matlab.ui.container.Panel
        infoGridLayout                matlab.ui.container.GridLayout
        Mode                          matlab.ui.control.DropDown
        infoHTML                      matlab.ui.control.HTML
        ModeDropDownLabel             matlab.ui.control.Label
    end

    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            
        end

        % Button pushed function: Calculate
        function Calculate_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % call a corresponding method of the controller class
            
            app.winController.Calculate();
        end

        % Button pushed function: Close
        function Close_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.winController.closeWindow();
        end

        % Value changed function: Help
        function Help_Callback(app, event)
            % hObject    handle to closeBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            web('https://mib.helsinki.fi/help/main2/user-interface/plugins/organelle-analysis/golgi-orientation.html', '-browser');
        end

        % Value changed function: DistanceMaxFromNucleus, 
        % ...and 14 other components
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Button pushed function: selectInputDirsButton
        function selectInputDirsButtonPushed(app, event)
           app.winController.selectInputDirectories();
        end

        % Button pushed function: RemoveAllInputDir, RemoveSelectedInputDir
        function removeSelectedInputDir(app, event)
            switch event.Source.Tag
               case 'RemoveSelectedInputDir'
                   % remove selected directories
                   app.winController.removeSelectedDirectory('selected'); 
               case 'RemoveAllInputDir'
                   % remove all directories
                   app.winController.removeSelectedDirectory('all'); 
            end
        end

        % Button pushed function: selectCelloutlineModel, 
        % ...and 4 other components
        function selectOutputFilenamePushed(app, event)
            app.winController.selectOutputFilename(event.Source.Tag);
        end

        % Value changed function: Mode
        function ModeValueChanged(app, event)
            app.winController.selectMode();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Golgi orientation analysis';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.mainGridLayout = uigridlayout(app.Figure);
            app.ModeAndInformationPanel = uipanel(app.mainGridLayout);
            app.ModeAndInformationPanel.Title = 'Mode and Information';
            app.infoGridLayout = uigridlayout(app.ModeAndInformationPanel);
            app.ModeDropDownLabel = uilabel(app.infoGridLayout);
            app.ModeDropDownLabel.Text = 'Mode';
            app.infoHTML = uihtml(app.infoGridLayout);
            app.Mode = uidropdown(app.infoGridLayout);
            app.Mode.Items = {'Complete model', 'Cropped cells'};
            app.Mode.ValueChangedFcn = createCallbackFcn(app, @ModeValueChanged, true);
            app.Mode.Value = 'Complete model';
            app.TabGroup = uitabgroup(app.mainGridLayout);
            app.CompleteModelFilesTab = uitab(app.TabGroup);
            app.CompleteModelFilesTab.Title = 'Complete model files';
            app.CompleteModelFilesGridLayout = uigridlayout(app.CompleteModelFilesTab);
            app.ImageFilenameLabel = uilabel(app.CompleteModelFilesGridLayout);
            app.ImageFilenameLabel.Text = 'Image filename';
            app.selectImagePath = uibutton(app.CompleteModelFilesGridLayout, 'push');
            app.selectImagePath.ButtonPushedFcn = createCallbackFcn(app, @selectOutputFilenamePushed, true);
            app.selectImagePath.Text = '...';
            app.imagePath = uieditfield(app.CompleteModelFilesGridLayout, 'text');
            app.imagePath.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.golgiModelLabel = uilabel(app.CompleteModelFilesGridLayout);
            app.golgiModelLabel.Text = 'Golgi model';
            app.selectGolgiModel = uibutton(app.CompleteModelFilesGridLayout, 'push');
            app.selectGolgiModel.ButtonPushedFcn = createCallbackFcn(app, @selectOutputFilenamePushed, true);
            app.selectGolgiModel.Text = '...';
            app.golgiModelPath = uieditfield(app.CompleteModelFilesGridLayout, 'text');
            app.golgiModelPath.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.nucleiModelLabel = uilabel(app.CompleteModelFilesGridLayout);
            app.nucleiModelLabel.Text = 'Nuclei model';
            app.selectNucleiModel = uibutton(app.CompleteModelFilesGridLayout, 'push');
            app.selectNucleiModel.ButtonPushedFcn = createCallbackFcn(app, @selectOutputFilenamePushed, true);
            app.selectNucleiModel.Text = '...';
            app.nucleiModelPath = uieditfield(app.CompleteModelFilesGridLayout, 'text');
            app.nucleiModelPath.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.celloutlinesModelLabel = uilabel(app.CompleteModelFilesGridLayout);
            app.celloutlinesModelLabel.Text = 'Cell outlines model';
            app.selectCelloutlineModel = uibutton(app.CompleteModelFilesGridLayout, 'push');
            app.selectCelloutlineModel.ButtonPushedFcn = createCallbackFcn(app, @selectOutputFilenamePushed, true);
            app.selectCelloutlineModel.Text = '...';
            app.celloutlineModelPath = uieditfield(app.CompleteModelFilesGridLayout, 'text');
            app.celloutlineModelPath.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.CroppedCellsDirsTab = uitab(app.TabGroup);
            app.CroppedCellsDirsTab.Title = 'Cropped cells dirs';
            app.CroppedCellsDirsGridLayout = uigridlayout(app.CroppedCellsDirsTab);
            app.directoriesLeftGridLayout = uigridlayout(app.CroppedCellsDirsGridLayout);
            app.inputDirectoriesLabel = uilabel(app.directoriesLeftGridLayout);
            app.inputDirectoriesLabel.Text = 'Input directories';
            app.selectInputDirsButton = uibutton(app.directoriesLeftGridLayout, 'push');
            app.selectInputDirsButton.ButtonPushedFcn = createCallbackFcn(app, @selectInputDirsButtonPushed, true);
            app.selectInputDirsButton.Text = 'Add...';
            app.RemoveSelectedInputDir = uibutton(app.directoriesLeftGridLayout, 'push');
            app.RemoveSelectedInputDir.ButtonPushedFcn = createCallbackFcn(app, @removeSelectedInputDir, true);
            app.RemoveSelectedInputDir.Text = 'Remove selected';
            app.RemoveAllInputDir = uibutton(app.directoriesLeftGridLayout, 'push');
            app.RemoveAllInputDir.ButtonPushedFcn = createCallbackFcn(app, @removeSelectedInputDir, true);
            app.RemoveAllInputDir.Text = 'Remove all';
            app.InputDirectories = uilistbox(app.CroppedCellsDirsGridLayout);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.settingsGridLayout = uigridlayout(app.SettingsTab);
            app.topSettingsGridLayout = uigridlayout(app.settingsGridLayout);
            app.MethodDropDownLabel = uilabel(app.topSettingsGridLayout);
            app.MethodDropDownLabel.Text = 'Method';
            app.Method = uidropdown(app.topSettingsGridLayout);
            app.Method.Items = {'Relative to nucleus', 'Relative to cell boundary', 'Both'};
            app.Method.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.Method.Value = 'Relative to nucleus';
            app.midSettignsGridLayout = uigridlayout(app.settingsGridLayout);
            app.MaterialsPanel = uipanel(app.midSettignsGridLayout);
            app.MaterialsPanel.Title = 'Materials';
            app.materialsGridLayout = uigridlayout(app.MaterialsPanel);
            app.GolgiEditFieldLabel = uilabel(app.materialsGridLayout);
            app.GolgiEditFieldLabel.Text = 'Golgi';
            app.MaterialGolgi = uieditfield(app.materialsGridLayout, 'numeric');
            app.MaterialGolgi.Limits = [0 Inf];
            app.MaterialGolgi.RoundFractionalValues = 'on';
            app.MaterialGolgi.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.MaterialGolgi.Value = 3;
            app.NucleusEditFieldLabel = uilabel(app.materialsGridLayout);
            app.NucleusEditFieldLabel.Text = 'Nucleus';
            app.MaterialNucleus = uieditfield(app.materialsGridLayout, 'numeric');
            app.MaterialNucleus.Limits = [0 Inf];
            app.MaterialNucleus.RoundFractionalValues = 'on';
            app.MaterialNucleus.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.MaterialNucleus.Value = 2;
            app.CellshapeEditFieldLabel = uilabel(app.materialsGridLayout);
            app.CellshapeEditFieldLabel.Text = 'Cell shape';
            app.MaterialCell = uieditfield(app.materialsGridLayout, 'numeric');
            app.MaterialCell.Limits = [0 Inf];
            app.MaterialCell.RoundFractionalValues = 'on';
            app.MaterialCell.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.MaterialCell.Value = 1;
            app.SizethresholdPanel = uipanel(app.midSettignsGridLayout);
            app.SizethresholdPanel.Title = 'Size threshold';
            app.thresholdGridLayout = uigridlayout(app.SizethresholdPanel);
            app.ThresholdGolgi = uieditfield(app.thresholdGridLayout, 'numeric');
            app.ThresholdGolgi.ValueDisplayFormat = '%d';
            app.ThresholdGolgi.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ThresholdGolgi.Value = 10;
            app.ThresholdNucleus = uieditfield(app.thresholdGridLayout, 'numeric');
            app.ThresholdNucleus.ValueDisplayFormat = '%d';
            app.ThresholdNucleus.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ThresholdNucleus.Value = 10;
            app.ThresholdCell = uieditfield(app.thresholdGridLayout, 'numeric');
            app.ThresholdCell.ValueDisplayFormat = '%d';
            app.ThresholdCell.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ThresholdCell.Value = 10;
            app.DistancefromnucleusPanel = uipanel(app.midSettignsGridLayout);
            app.DistancefromnucleusPanel.Title = 'Distance from nucleus';
            app.GridLayout3 = uigridlayout(app.DistancefromnucleusPanel);
            app.DistanceMaxFromNucleus = uieditfield(app.GridLayout3, 'numeric');
            app.DistanceMaxFromNucleus.ValueDisplayFormat = '%d';
            app.DistanceMaxFromNucleus.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.DistanceMaxFromNucleus.Value = 200;
            app.outputFileGridLayout = uigridlayout(app.mainGridLayout);
            app.FilenameextensionsLabel = uilabel(app.outputFileGridLayout);
            app.FilenameextensionsLabel.Text = 'Filename extensions';
            app.filenameExtensionGridLayout = uigridlayout(app.outputFileGridLayout);
            app.ImagesDropDownLabel = uilabel(app.filenameExtensionGridLayout);
            app.ImagesDropDownLabel.Text = 'Images';
            app.FilenameImageExtension = uidropdown(app.filenameExtensionGridLayout);
            app.FilenameImageExtension.Items = {'AM', 'TIF'};
            app.FilenameImageExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.FilenameImageExtension.Value = 'AM';
            app.ModelsDropDownLabel = uilabel(app.filenameExtensionGridLayout);
            app.ModelsDropDownLabel.Text = 'Models';
            app.FilenameModelExtension = uidropdown(app.filenameExtensionGridLayout);
            app.FilenameModelExtension.Items = {'MODEL', 'TIF'};
            app.FilenameModelExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.FilenameModelExtension.Value = 'MODEL';
            app.outputSubfolderLabel = uilabel(app.outputFileGridLayout);
            app.outputSubfolderLabel.Text = 'Output filename';
            app.selectOutputFilename = uibutton(app.outputFileGridLayout, 'push');
            app.selectOutputFilename.ButtonPushedFcn = createCallbackFcn(app, @selectOutputFilenamePushed, true);
            app.selectOutputFilename.Tooltip = {'Select output filename'};
            app.selectOutputFilename.Text = '...';
            app.OutputFilename = uieditfield(app.outputFileGridLayout, 'text');
            app.OutputFilename.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.OutputFilename.Value = 'GolgiAnalysis.xls';
            app.buttomGridLayout = uigridlayout(app.mainGridLayout);
            app.Help = uibutton(app.buttomGridLayout, 'state');
            app.Help.ValueChangedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.Help.Text = 'Help';
            app.Calculate = uibutton(app.buttomGridLayout, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @Calculate_Callback, true);
            app.Calculate.Text = 'Calculate';
            app.Close = uibutton(app.buttomGridLayout, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @Close_Callback, true);
            app.Close.Text = 'Close';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GolgiOrientationAnalysisGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end